classdef SSTAR_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SSTARv10UIFigure                matlab.ui.Figure
        Image                           matlab.ui.control.Image
        HTML2                           matlab.ui.control.HTML
        SAVEButton                      matlab.ui.control.Button
        HELPButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DATAPROCESSINGTab               matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        PlotcompletionROIsButton        matlab.ui.control.Button
        OutputfolderpathEditField       matlab.ui.control.EditField
        OutputfolderpathEditFieldLabel  matlab.ui.control.Label
        DatafolderpathEditField         matlab.ui.control.EditField
        DatafolderpathEditFieldLabel    matlab.ui.control.Label
        ROIsselectionDropDown           matlab.ui.control.DropDown
        ROIsselectionDropDownLabel      matlab.ui.control.Label
        PlotcenterROIsButton            matlab.ui.control.Button
        PlotareaofanalysisButton        matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        InstrumentDropDown              matlab.ui.control.DropDown
        InstrumentDropDownLabel         matlab.ui.control.Label
        DeleteexistingoutputfilesDropDown  matlab.ui.control.DropDown
        DeleteexistingoutputfilesDropDownLabel  matlab.ui.control.Label
        LatitudeofthereferencelocationEditField  matlab.ui.control.NumericEditField
        LatitudeofthereferencelocationEditFieldLabel  matlab.ui.control.Label
        LongitudeofthereferencelocationEditField  matlab.ui.control.NumericEditField
        LongitudeofthereferencelocationEditFieldLabel  matlab.ui.control.Label
        CoordinatesSNkmEditField        matlab.ui.control.EditField
        CoordinatesSNkmEditFieldLabel   matlab.ui.control.Label
        CoordinatesWEkmEditField        matlab.ui.control.EditField
        CoordinatesWEkmEditFieldLabel   matlab.ui.control.Label
        TIMESERIESTab                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        ExportdatatocsvCheckBox         matlab.ui.control.CheckBox
        ConfigurationfileEditField_2    matlab.ui.control.EditField
        ConfigurationfileLabel          matlab.ui.control.Label
        ReferencedatesCheckBox          matlab.ui.control.CheckBox
        PlotareaofanalysisButton_2      matlab.ui.control.Button
        InterpolationmethodDropDown     matlab.ui.control.DropDown
        InterpolationmethodDropDownLabel  matlab.ui.control.Label
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        REFButton                       matlab.ui.control.RadioButton
        ROIButton                       matlab.ui.control.RadioButton
        AnomalyButton                   matlab.ui.control.RadioButton
        PlotavailableROIsButton         matlab.ui.control.Button
        InterpolationmethodDropDownLabel_2  matlab.ui.control.Label
        TemporalcorrectiondaysEditField_2  matlab.ui.control.EditField
        TemporalcorrectiondaysEditField_2Label  matlab.ui.control.Label
        RUNButton_2                     matlab.ui.control.Button
        DeletepreviousresultsCheckBox   matlab.ui.control.CheckBox
        PlotsetupButton                 matlab.ui.control.Button
        FiltertypeDropDown              matlab.ui.control.DropDown
        FiltertypeDropDownLabel         matlab.ui.control.Label
        PlottimeseriesButton            matlab.ui.control.Button
        ScenariotoplotDropDown          matlab.ui.control.DropDown
        ScenariotoplotDropDownLabel     matlab.ui.control.Label
        ROItoplotDropDown               matlab.ui.control.DropDown
        ROItoplotLabel                  matlab.ui.control.Label
        ReferenceROIDropDown            matlab.ui.control.DropDown
        ReferenceROIDropDownLabel       matlab.ui.control.Label
        ROIstoanalyzeDropDown           matlab.ui.control.DropDown
        ROIstoanalyzeDropDownLabel      matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        ExteriorButton                  matlab.ui.control.RadioButton
        InteriorButton                  matlab.ui.control.RadioButton
        SidereferencekmDropDown         matlab.ui.control.DropDown
        SidereferencekmDropDownLabel    matlab.ui.control.Label
        SideROIkmDropDown               matlab.ui.control.DropDown
        SideROIkmDropDownLabel          matlab.ui.control.Label
        IterationsforuncertaintyquantificationEditField  matlab.ui.control.EditField
        IterationsforuncertaintyquantificationEditFieldLabel  matlab.ui.control.Label
        AcceptancethresholdEditField    matlab.ui.control.EditField
        AcceptancethresholdEditFieldLabel  matlab.ui.control.Label
        OrderofthelowpassfilteryearsEditField  matlab.ui.control.EditField
        LightconditionsDropDown         matlab.ui.control.DropDown
        LightconditionsDropDownLabel    matlab.ui.control.Label
        SPATIOTEMPORALMAPTab            matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        ExportCSVCheckBox               matlab.ui.control.CheckBox
        ReferenceendDatePicker          matlab.ui.control.DatePicker
        ReferenceendLabel               matlab.ui.control.Label
        ReferencestartDatePicker        matlab.ui.control.DatePicker
        ReferencestartLabel             matlab.ui.control.Label
        EndDatePicker                   matlab.ui.control.DatePicker
        EndLabel                        matlab.ui.control.Label
        StartDatePicker                 matlab.ui.control.DatePicker
        StartLabel                      matlab.ui.control.Label
        ResolutiongridpointsEditField   matlab.ui.control.NumericEditField
        ResolutionLabel                 matlab.ui.control.Label
        SaveindividualframesCheckBox    matlab.ui.control.CheckBox
        ConfigurationfileEditField_3    matlab.ui.control.EditField
        ConfigurationfileEditField_3Label  matlab.ui.control.Label
        TypeofrepresentationLabel       matlab.ui.control.Label
        SpecificdateDatePicker          matlab.ui.control.DatePicker
        DateLabel                       matlab.ui.control.Label
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        SpatiallynormalizedButton       matlab.ui.control.RadioButton
        NormalizedcumulativeButton      matlab.ui.control.RadioButton
        AbsoluteButton                  matlab.ui.control.RadioButton
        ScenariotomapDropDown           matlab.ui.control.DropDown
        ScenariotomapDropDownLabel      matlab.ui.control.Label
        PlotareaofanalysisButton_3      matlab.ui.control.Button
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        ROIButton_2                     matlab.ui.control.RadioButton
        AnomalyButton_2                 matlab.ui.control.RadioButton
        PlotmapforspecificdateButton    matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        AnomalylimitsKEditFieldLabel_3  matlab.ui.control.Label
        AnomalylimitsKEditFieldLabel_2  matlab.ui.control.Label
        CREATEANIMATIONButton           matlab.ui.control.Button
        AnomalylimitsKEditField_2       matlab.ui.control.EditField
        TransparencySlider              matlab.ui.control.Slider
        TransparencyLabel               matlab.ui.control.Label
        TemporalcorrectiondaysEditField  matlab.ui.control.EditField
        TemporalcorrectiondaysEditFieldLabel  matlab.ui.control.Label
        AnomalylimitsKEditField         matlab.ui.control.EditField
        AnomalylimitsKEditFieldLabel    matlab.ui.control.Label
        WildfiremaskCheckBox            matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stop_sim=false; 
        RightMapAxes 
        animation=true;
        hasPlotted=false;
    end
    
    properties (Access = public)
        W_side
        E_side
        N_side
        S_side
        I 
        xl
        yl
        start_date_num
        end_date_num
    end

    methods (Access = private) 
        function switchToPlotMode(app, enablePlot)
            app.UIFigure.Icon = 'SSTAR_logo.png';
            if enablePlot
                app.UIAxes.Visible = 'on';
                app.Image.Visible = 'off';
            else
                app.UIAxes.Visible = 'off';
                app.Image.Visible = 'on';
            end
        end
        function updateLog(app, message, type)           
            switch type
                case 'info'
                    style = ['style="color: black; font-family: monospace; ', ...
                            'font-size: 11px;"'];
                case 'progress'
                    style = ['style="color: black; font-family: monospace; ', ...
                            'font-size: 11px; font-weight: bold;"'];
                case 'warning'
                    style = ['style="color: #FF6600; font-family: monospace; ', ...
                            'font-size: 11px;"'];
                case 'error'
                    style = ['style="color: red; font-family: monospace; ', ...
                            'font-size: 11px;"'];
                case 'plot'
                    style = ['style="color: blue; font-family: monospace; ', ...
                            'font-size: 11px; "'];
            end
            
            if ~iscell(message)
                message = {message};
            end
            
            % Handle empty messages - just clear the specific line
            if isempty(message{1})
                if strcmp(type, 'progress')
                    % Clear only progress line
                    createEmptyHTML(app);
                end
                return;
            end
            
            % Initialize base HTML if empty
            if isempty(app.HTML2.HTMLSource)
                createEmptyHTML(app);
            end
            
            % Remove < and > if they are at the start/end of the message
            messageStr = message{1};
            if messageStr(1) == '<';messageStr = messageStr(2:end);end
            if messageStr(end) == '>';messageStr = messageStr(1:end-1);end
            
            if strcmp(type, 'progress')
                styledMessage = sprintf('<div class="progress" %s>%s\n</div>', style, strtrim(messageStr));
            else
                styledMessage = sprintf('<div class="message" %s>%s\n</div>', style, strtrim(messageStr));
            end
            
            % Update HTML content
            updateHTMLContent(app, styledMessage, type);
            drawnow;
        
            function updateHTMLContent(app, newMessage, type)
                % Updates HTML content while maintaining message history
                currentHTML = app.HTML2.HTMLSource;
                
                % Extract existing messages
                allDivs = regexp(currentHTML, '<div[^>]*>.*?</div>', 'match');
                progressDiv = '';
                messageDivs = {};
                
                for i = 1:length(allDivs)
                    if contains(allDivs{i}, 'class="progress"')
                        progressDiv = allDivs{i};
                    elseif contains(allDivs{i}, 'class="message"') && ~isempty(regexp(allDivs{i}, '\S', 'once'))
                        messageDivs{end+1} = allDivs{i};
                    end
                end
                
                % Update messages based on type
                if strcmp(type, 'progress')
                    progressDiv = newMessage;
                else
                    messageDivs = [{newMessage} messageDivs(1:min(end,1))];
                    %messageDivs = {newMessage}; %only one non-progress message shown
                end
                
                % Ensure required divs exist
                if isempty(progressDiv)
                    progressDiv = '<div class="progress" style="min-height: 1.2em;"></div>';
                end
                while length(messageDivs) < 2
                    messageDivs{end+1} = '<div class="message" style="min-height: 1.2em;"></div>';
                end
                
                % Create new HTML
                bodyContent = ['<div class="message-container">', ...
                              progressDiv, strjoin(messageDivs, ''), '</div>'];
                              
                app.HTML2.HTMLSource = createBaseHTML(bodyContent);
            end
            
            function html = createBaseHTML(bodyContent)
                % Creates base HTML structure
                html = ['<html><head><style>', ...
                        'body { font-family: monospace; margin: 4px; background-color: white; cursor: text; }', ...
                        '.message-container { width: 100%; }', ...
                        '</style></head><body>', bodyContent, '</body></html>'];
            end
            function createEmptyHTML(app)
                % Creates empty HTML structure
                emptyContent = ['<div class="message-container">', ...
                               '<div class="progress" style="min-height: 1.2em;"></div>', ...
                               '<div class="message" style="min-height: 1.2em;"></div>', ...
                               '<div class="message" style="min-height: 1.2em;"></div>', ...
                               '</div>'];
                app.HTML2.HTMLSource = createBaseHTML(emptyContent);
            end
        end

        function stopSimulation(app)
            app.stop_sim = true;  
            updateLog(app, 'RUN STOPPED BY USER', 'warning');
        end     
        function showROITooltip(app, src, event, X, Y, roiNames)
            clickPos = event.IntersectionPoint(1:2);
            
            % Find nearest ROI point
            distances = sqrt((X - clickPos(1)).^2 + (Y - clickPos(2)).^2);
            [minDist, idx] = min(distances);
            
            % Only show tooltip if click is within a reasonable distance
            axXRange = diff(app.UIAxes.XLim);
            axYRange = diff(app.UIAxes.YLim);
            threshold = 0.05 * max(axXRange, axYRange); % 5% of axes range
            
            % Delete previous tooltip
            delete(findobj(app.UIAxes, 'Tag', 'ROITooltip'));
            
            if minDist > threshold
                return; % Click was too far from any ROI point
            end
            
            text(app.UIAxes, X(idx), Y(idx), ...
                sprintf('  %s\n  X: %.1f km\n  Y: %.1f km', ...
                roiNames{idx}, X(idx), Y(idx)), ...
                'FontSize', 12, ...
                'Color', 'white', ...
                'BackgroundColor', [0.2 0.2 0.2], ...
                'EdgeColor', 'black', ...
                'Margin', 4, ...
                'Tag', 'ROITooltip', ...
                'VerticalAlignment', 'bottom', ...
                'HorizontalAlignment', 'left', ...
                'Interpreter', 'none', ...
                'HitTest', 'off');  % Prevent tooltip text from blocking future clicks
        end
        function showROITooltip2(app, src, event, X, Y, roiNames)
            % Get click position
            clickPos = event.IntersectionPoint(1:2);
            
            % Find nearest ROI point
            distances = sqrt((X - clickPos(1)).^2 + (Y - clickPos(2)).^2);
            [~, idx] = min(distances);
            
            % Delete previous tooltip text if exists
            delete(findobj(app.UIAxes, 'Tag', 'ROITooltip'));
            
            % Display coordinates as text on axes
            txt = text(app.UIAxes, X(idx), Y(idx), ...
                sprintf('  %s\n  X: %.1f km\n  Y: %.1f km', ...
                roiNames{idx}, X(idx), Y(idx)), ...
                'FontSize', 12, ...
                'Color', 'white', ...
                'BackgroundColor', [0.2 0.2 0.2], ...
                'EdgeColor', 'black', ...
                'Margin', 4, ...
                'Tag', 'ROITooltip', ...
                'VerticalAlignment', 'bottom', ...
                'HorizontalAlignment', 'left', ...
                'Interpreter', 'none');
            
            % Remove tooltip on next click anywhere on axes
            app.UIAxes.ButtonDownFcn = @(~,~) delete(findobj(app.UIAxes, 'Tag', 'ROITooltip'));
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%% SCRIPT 1 %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function startSimulation(app)
            app.stop_sim = false;
            satellite=app.InstrumentDropDown.Value;satellite=satellite(7:end);  
            data_folder = app.DatafolderpathEditField.Value;
            path = fullfile(data_folder, ['MODIS_Level_1B_',satellite]);
            path_geolocation=fullfile(data_folder, ['MODIS_Geolocation_',satellite]);
            path_mask=fullfile(data_folder, ['MODIS_Cloud_mask_',satellite]);
            output_folder = app.OutputfolderpathEditField.Value;

            lat=app.LatitudeofthereferencelocationEditField.Value;
            lon=app.LongitudeofthereferencelocationEditField.Value;
            rois_selection=app.ROIsselectionDropDown.Value;
            devlat_vector=str2num(app.CoordinatesSNkmEditField.Value);
            devlon_vector=str2num(app.CoordinatesWEkmEditField.Value);
            edi_lat_km=[1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20 20.5 25 30 35 40];
            edi_lon_km=edi_lat_km;
            delete_existing_output_files=app.DeleteexistingoutputfilesDropDown.Value;

            
            %% (2) Remove files (if incomplete downloading)            
            data=dir(fullfile(path,'*.hdf'));
            data_geolocation=dir(fullfile(path_geolocation,'*.hdf'));
            data_mask=dir(fullfile(path_mask,'*.hdf'));

            while length(data)~=length(data_geolocation) || length(data)~=length(data_mask) || length(data_geolocation)~=length(data_mask)
                % remove files in data and data_geolocation that do not correspond to the same scene 
                for i=1:length(data);identificator1{i}=data(i).name(10:22);end
                for i=1:length(data_geolocation);identificator2{i}=data_geolocation(i).name(7:19);end
                [data, data_geolocation] = keep_matching_data(data, identificator1, data_geolocation, identificator2); %remove duplicate and extra files
                clear identificator1 identificator2;

                % remove files in data and data_mask that do not correspond to the same scene 
                for i=1:length(data);identificator1{i}=data(i).name(10:22);end
                for i=1:length(data_mask);identificator2{i}=data_mask(i).name(10:22);end
                [data, data_mask] = keep_matching_data(data, identificator1, data_mask, identificator2); %remove duplicate and extra files
                clear identificator1 identificator2;

                % remove files in geolocation data and data_mask that do not correspond to the same scene 
                for i=1:length(data_geolocation);identificator1{i}=data_geolocation(i).name(7:19);end
                for i=1:length(data_mask);identificator2{i}=data_mask(i).name(10:22);end
                [data_geolocation, data_mask] = keep_matching_data(data_geolocation, identificator1, data_mask, identificator2); %remove duplicate and extra files
                clear identificator1 identificator2;
            end
            updateLog(app, sprintf('Number of ".hdf" radiance/geolocation/mask files to analyze: %d', length(data)), 'progress');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% (3) Main code  
            % determine date to run from
            if app.stop_sim==true;return;end
            [min_row, should_stop] = determineStartRow(app, output_folder, satellite, length(data));
            if should_stop
                updateLog(app, 'STOPPED -- Check coordinates or delete existing files to restart.', 'error');
                return;
            end
            updateLog(app, sprintf('Starting processing from file %d over %d', min_row, length(data)), 'info');
            if app.stop_sim==true;return;end

            for numberband=[31]          
                file=min_row;
                last_success_file = min_row - 1;
                successful_saves = 0;  
                consecutive_errors = 0;  % Track network disconnection
                max_consecutive_errors = 5;

                while file<=length(data) && app.stop_sim==false
                    if app.stop_sim==true;break;end
                    updateLog(app, sprintf('RUNNING... %.3f%% of data files analyzed', 100*(file-1)/length(data)), 'progress');
                    fclose('all');
                    
                    FILE_NAME = fullfile(path, data(file).name);
                    SWATH_NAME = 'MODIS_SWATH_Type_L1B';
                    FILE_NAME_geolocation = fullfile(path_geolocation, data_geolocation(file).name);
                    SWATH_NAME_geolocation = 'MODIS_Swath_Type_GEO';
                    FILE_NAME_mask = fullfile(path_mask, data_mask(file).name);

                    try
                        [bands,uncertainty_bands,latitude,longitude,Mask_0,Mask_1,Mask_2,Mask_3]=read_data(FILE_NAME,SWATH_NAME,FILE_NAME_geolocation,SWATH_NAME_geolocation,FILE_NAME_mask);
                        if app.stop_sim==true;break;end
                        % Extract the radiance of each band and uncertainty indexes
                        [band,uncertainty_indexes]=extract_data(numberband,bands,uncertainty_bands);
                        % Reading attributes 
                        [scale_band,offset_band,specified_uncertainty_band,scaling_factor_band]=attributes(FILE_NAME,numberband);
                        % Calculate radiance and uncertainty for each pixel
                        radiance_band=scale_band*(band-offset_band);
                        relative_error=specified_uncertainty_band*exp(uncertainty_indexes/scaling_factor_band);
                        absolute_error=radiance_band.*relative_error/100;
                        if app.stop_sim==true;break;end

                        if strcmp(rois_selection,'Grid');[X,Y]=meshgrid(devlon_vector,devlat_vector);end
                        if strcmp(rois_selection,'Specific locations');X=devlon_vector;Y=devlat_vector;end               
                        for loc_index=1:numel(X)
                            if app.stop_sim==true;break;end
                            devlat=Y(loc_index);
                            devlon=X(loc_index);
  
                                try
                                    %create directories                                    
                                    output_dir = fullfile(output_folder, sprintf('%g_%g', devlon, devlat));
                                    mkdir(output_dir);warning('off', 'MATLAB:MKDIR:DirectoryExists');  
                                    %center of the region of interest
                                    lat_gridpoint=lat+devlat/111;
                                    lon_gridpoint=lon+devlon/(pi*6371*cosd(lat_gridpoint)/180);
                                    %smaller amplitude roi --> distances in km; then are converted to degrees
                                    edi_lat=edi_lat_km/111;
                                    edi_lon=edi_lon_km/(pi*6371*cosd(lat_gridpoint)/180);
                                    %maximum amplitude to explore
                                    roi_lat=max(edi_lat);
                                    roi_lon=max(edi_lon);
                                    %Crop area around the target
                                    [radiance_band_cropped,latitude_cropped,longitude_cropped,absolute_error_cropped,Mask_1_cropped,daynight]=crop(radiance_band,latitude,longitude,absolute_error,lat_gridpoint,roi_lat,lon_gridpoint,roi_lon,Mask_0,Mask_1,Mask_2,Mask_3);
                                    %Select ROIs and incorporate masks
                                    [radiance_band_masked,radiance_band_subarea_masked,radiance_band_surroundings_masked,absolute_error_masked,absolute_error_subarea_masked,absolute_error_surroundings_masked]=rois_mask(radiance_band_cropped,latitude_cropped,longitude_cropped,absolute_error_cropped,edi_lat,edi_lon,lat_gridpoint,lon_gridpoint,Mask_1_cropped);                                           
                                    %Calculation of parameters
                                    if app.stop_sim==true;break;end
                                    [number_pixels_Ln,number_pixels_Ls,Ln,Ls]=calculate_parameters2(radiance_band_subarea_masked,radiance_band_surroundings_masked,absolute_error_subarea_masked,absolute_error_surroundings_masked);    %Bootstrapping at the end to estimate uncertainties
                                    %retrieve the date of the scene
                                    date=date_scene(data,file);
                                    %save results in .txt file
                                    if app.stop_sim==true;break;end
                                    save_to_file2(date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, numberband, satellite, daynight, devlon, devlat, app);

                                catch
                                    %create directories                                    
                                    output_dir = fullfile(output_folder, sprintf('%g_%g', devlon, devlat));
                                    mkdir(output_dir);warning('off', 'MATLAB:MKDIR:DirectoryExists');
                                    %set all the parameters to NaN in case there is an error
                                    [number_pixels_Ln,number_pixels_Ls,Ln,Ls,sigma_Ln,sigma_Ls,daynight]=calculate_parameters_NaN(edi_lat_km);
                                    %retrieve the date of the scene in matlab universal time
                                    date=date_scene(data,file);
                                    %save results in .txt file
                                    save_to_file2(date,Ln,Ls,number_pixels_Ln,number_pixels_Ls,numberband,satellite,daynight,devlon,devlat,app);
                                end
                        end
                        successful_saves = successful_saves + 1;
                        consecutive_errors = 0;  % Reset on success
                    catch
                        consecutive_errors = consecutive_errors + 1;
                        if consecutive_errors >= max_consecutive_errors
                            updateLog(app, sprintf('Network/data access error after %d consecutive failures. Stopped at file %d/%d.', max_consecutive_errors, last_success_file, length(data)), 'error');
                            app.stop_sim = true;
                            break;
                        end
                    end
                    file=file+1;
                end
                %updateLog(app, 'RUN ENDED', 'info');
                if successful_saves == length(data) - min_row + 1
                    updateLog(app, sprintf('RUN ENDED - all %d files processed', successful_saves), 'info');
                elseif app.stop_sim && successful_saves < length(data) - min_row + 1
                    updateLog(app, sprintf('RUN INTERRUPTED - %d/%d files successfully processed before disconnection', last_success_file, length(data)), 'warning');
                else
                    updateLog(app, sprintf('RUN ENDED - %d/%d files processed', last_success_file, length(data)), 'info');
                end
                app.stop_sim = true;
            end


            function [min_row, should_stop] = determineStartRow(app, output_folder, satellite, total_files)
                should_stop = false;
                devlat_vector = str2num(app.CoordinatesSNkmEditField.Value);
                devlon_vector = str2num(app.CoordinatesWEkmEditField.Value);
            
                % Create folder for the sector
                locations = {}; k = 1;
                for i = 1:length(devlat_vector)
                    for j = 1:length(devlon_vector)
                        locations{k} = sprintf('%g_%g', devlon_vector(j), devlat_vector(i)); 
                        k = k + 1;
                    end
                end
            
                % If delete existing files: delete and start from beginning
                if strcmp(app.DeleteexistingoutputfilesDropDown.Value, 'Yes')
                    for i = 1:length(locations)
                        filepath = fullfile(output_folder, locations{i}, sprintf('results_band31_%s.txt', satellite));
                        if exist(filepath, 'file'); delete(filepath); end
                    end
                    min_row = 1; 
                    return;
                end
            
                % Check existing files and row counts
                row_counts = zeros(length(locations), 1);
                files_exist = false(length(locations), 1);
                
                % First pass: gather information about all files
                for i = 1:length(locations)
                    filepath = fullfile(output_folder, locations{i}, sprintf('results_band31_%s.txt', satellite));
                    if exist(filepath, 'file')
                        try
                            datar = load(filepath);
                            row_counts(i) = size(datar, 1);
                            files_exist(i) = true;
                        catch err
                            % If load fails, try to fix the file
                            if contains(err.message, 'same number of columns')
                                updateLog(app, 'Fixing corrupted file ...', 'warning');

                                try
                                    % Read file as text
                                    fid = fopen(filepath, 'r');
                                    lines = textscan(fid, '%s', 'Delimiter', '\n');
                                    fclose(fid);
                                    lines = lines{1};
                                    
                                    % Remove last potentially incomplete line
                                    lines = lines(1:end-1);
                                    
                                    % Write back
                                    fid = fopen(filepath, 'w');
                                    fprintf(fid, '%s\n', lines{:});
                                    fclose(fid);
                                    
                                    % Try loading again
                                    datar = load(filepath);
                                    row_counts(i) = size(datar, 1);
                                    files_exist(i) = true;
                                    
                                    updateLog(app, 'File fixed successfully. Continuing but might take time ...', 'info');
                                catch fix_err
                                    updateLog(app, sprintf('Could not fix file: %s', fix_err.message), 'error');
                                    row_counts(i) = 0;
                                    files_exist(i) = false;
                                end
                            else
                                updateLog(app, sprintf('Error reading file: %s', err.message), 'error');
                                row_counts(i) = 0;
                                files_exist(i) = false;
                            end
                        end
                    end
                end
            
                % Analyze the situation
                existing_files = sum(files_exist);
                
                if existing_files == 0
                    % No files exist, start from beginning
                    min_row = 1;
                    updateLog(app, sprintf('No existing output found. Starting from beginning for %d locations.', length(locations)), 'info');
                    return;
                end
                
                unique_counts = unique(row_counts(row_counts > 0));
                if length(unique_counts) == 1 && existing_files == length(locations)
                    % All existing files have the same number of rows - good case
                    min_row = unique_counts(1) + 1;
                    updateLog(app, sprintf('Processing files %d to %d for all %d locations', unique_counts(1) + 1, total_files, length(locations)), 'info');
                else
                    % Find minimum complete row count
                    min_complete_rows = min(row_counts(row_counts > 0));
                    warning_msg = sprintf('INCONSISTENCY: ');
    
                    % Report missing locations if any
                    if existing_files < length(locations)
                        warning_msg = [warning_msg sprintf('%d/%d locations processed. ', ...
                            existing_files, length(locations))];
                        should_stop = true;  % This will stop the code
                        min_row = min_complete_rows + 1;
                        return;  % Exit function early
                    end
                    % Report and handle different file lengths if any
                    if length(unique_counts) > 1
                        warning_msg = [warning_msg sprintf('Different output lengths %d-%d rows/%d. ', min(unique_counts), max(unique_counts),total_files)];
                            
                        % Truncate files that have extra rows
                        for i = 1:length(locations)
                            if row_counts(i) > min_complete_rows
                                filepath = fullfile(output_folder, locations{i}, sprintf('results_band31_%s.txt', satellite));
                                try
                                    datal = load(filepath);
                                    % Truncate and rewrite file
                                    fid = fopen(filepath, 'w');
                                    for row = 1:min_complete_rows
                                        fprintf(fid, '%.6e\t\t', datal(row,1));
                                        for col = 2:size(datal,2)-1
                                            if col <= (size(datal,2)-1)/2
                                                fprintf(fid, '%.6e\t\t', datal(row,col));
                                            else
                                                fprintf(fid, '%d\t\t', round(datal(row,col)));
                                            end
                                        end
                                        fprintf(fid, '%d\r\n', round(datal(row,end)));
                                    end
                                    fclose(fid);
                                catch
                                    continue;
                                end
                            end
                        end
                        warning_msg = [warning_msg sprintf('Outputs aligned to %d rows. Continuing... ', min_complete_rows)];
                        min_row = min_complete_rows + 1;
                    end
                    updateLog(app, warning_msg, 'warning');
                end
            end
            
            function [bands,uncertainty_bands,latitude,longitude,Mask_0,Mask_1,Mask_2,Mask_3]=read_data(FILE_NAME,SWATH_NAME,FILE_NAME_geolocation,SWATH_NAME_geolocation,FILE_NAME_mask)
                import matlab.io.hdfeos.*
            
                file_id = sw.open(FILE_NAME, 'rdonly');
                swath_id = sw.attach(file_id, SWATH_NAME);     
                bands=double(sw.readField(swath_id, 'EV_1KM_Emissive',[],[],[]));
                uncertainty_bands=double(sw.readField(swath_id, 'EV_1KM_Emissive_Uncert_Indexes',[],[],[]));            
                sw.detach(swath_id);
                sw.close(file_id);
            
                file_id = sw.open(FILE_NAME_geolocation, 'rdonly');   
                swath_id = sw.attach(file_id, SWATH_NAME_geolocation); 
                latitude=double(sw.readField(swath_id, 'Latitude',[],[],[]));
                longitude=double(sw.readField(swath_id, 'Longitude',[],[],[]));
                sw.detach(swath_id);
                sw.close(file_id); 
                 
                Cloud_Mask = hdfread([FILE_NAME_mask], '/mod35/Data Fields/Cloud_Mask', 'Index', {[],[],[]});
                size_aux=size(Cloud_Mask);
                aux=dec2bin(typecast(int8(Cloud_Mask(1,:)),'uint8'),8);
                aux2=aux(:,6);aux3=aux(:,7);aux4=aux(:,5);  
               
                Mask_0=ones(size_aux(2),size_aux(3));Mask_0=Mask_0'; %No mask
                Mask_1=zeros(size_aux(2),size_aux(3));Mask_1(find(aux2=='1' & aux3=='1'))=1;Mask_1=Mask_1'; %Choose cloud-free pixels (daytime and nighttime)
                Mask_2=zeros(size_aux(2),size_aux(3));Mask_2(find(aux2=='1' & aux3=='1' & aux4=='1'))=1;Mask_2=Mask_2'; %Choose cloud-free pixels (daytime ONLY) 
                Mask_3=zeros(size_aux(2),size_aux(3));Mask_3(find(aux2=='1' & aux3=='1' & aux4=='0'))=1;Mask_3=Mask_3'; %Choose cloud-free pixels (nighttime ONLY)    
            end
            function [band, uncertainty_indexes] = extract_data(numberband, bands, uncertainty_bands)
                % Get specified band with its uncertainties
                if numberband >= 27
                    band = bands(:,:,numberband-20);
                    uncertainty_indexes = uncertainty_bands(:,:,numberband-20);
                else
                    band = bands(:,:,numberband-19);
                    uncertainty_indexes = uncertainty_bands(:,:,numberband-19);
                end
            end

            function [scale_band,offset_band,specified_uncertainty_band,scaling_factor_band]=attributes(FILE_NAME,numberband) 
                import matlab.io.hdf4.*    
            
                SD_id = sd.start(FILE_NAME, 'rdonly');
                sds_index = sd.nameToIndex(SD_id, 'EV_1KM_Emissive');
                sds_id = sd.select(SD_id, sds_index);           
                scale_index = sd.findAttr(sds_id, 'radiance_scales');
                scale = double(sd.readAttr(sds_id, scale_index));           
                offset_index = sd.findAttr(sds_id, 'radiance_offsets');
                offset = double(sd.readAttr(sds_id, offset_index)); 
                
                sds_index = sd.nameToIndex(SD_id, 'EV_1KM_Emissive_Uncert_Indexes');
                sds_id2 = sd.select(SD_id, sds_index);
                specified_uncertainty_index=sd.findAttr(sds_id2, 'specified_uncertainty');
                specified_uncertainty=double(sd.readAttr(sds_id2, specified_uncertainty_index));
                scaling_factor_index=sd.findAttr(sds_id2,'scaling_factor');
                scaling_factor=double(sd.readAttr(sds_id2, scaling_factor_index));   
                
                sd.endAccess(sds_id);
                sd.endAccess(sds_id2);
                sd.close(SD_id);                       
                        
                if numberband<27;aux=numberband-19;else;aux=numberband-20;end
                scale_band=scale(aux);
                offset_band=offset(aux);
                specified_uncertainty_band=specified_uncertainty(aux);
                scaling_factor_band=scaling_factor(aux);      
            end

            function [radiance_band, latitude, longitude, absolute_error, Mask_1, daynight] = crop(radiance_band, latitude, longitude, absolute_error, lat, roi_lat, lon, roi_lon, Mask_0, Mask_1, Mask_2, Mask_3)
                % Crops the input matrices based on the specified ROI and determine if the data is day or night
                % Create masks for latitude and longitude limits
                lat_mask = latitude < (lat - roi_lat) | latitude > (lat + roi_lat);
                lon_mask = longitude < (lon - roi_lon) | longitude > (lon + roi_lon);
                combined_mask = lat_mask | lon_mask;
                
                % Apply the mask to matrices
                radiance_band(combined_mask) = NaN;
                latitude(combined_mask) = NaN;
                longitude(combined_mask) = NaN;
                absolute_error(combined_mask) = NaN;
                Mask_0(combined_mask) = NaN;
                Mask_1(combined_mask) = NaN;
                Mask_2(combined_mask) = NaN;
                Mask_3(combined_mask) = NaN;
                
                % Remove rows and columns with only NaN
                valid_rows = any(~isnan(radiance_band), 2);
                valid_cols = any(~isnan(radiance_band), 1);
            
                % Crop matrices
                radiance_band = radiance_band(valid_rows, valid_cols);
                latitude = latitude(valid_rows, valid_cols);
                longitude = longitude(valid_rows, valid_cols);
                absolute_error = absolute_error(valid_rows, valid_cols);
                Mask_0 = Mask_0(valid_rows, valid_cols);
                Mask_1 = Mask_1(valid_rows, valid_cols);
                Mask_2 = Mask_2(valid_rows, valid_cols);
                Mask_3 = Mask_3(valid_rows, valid_cols);
                
                % Day or night
                daynight = nansum(Mask_1(:) - Mask_2(:)) ~= 0;
            end

            function [radiance_band,radiance_band_subarea,radiance_band_surroundings,absolute_error,absolute_error_subarea,absolute_error_surroundings]=rois_mask(radiance_band,latitude,longitude,absolute_error,edi_lat,edi_lon,lat,lon,Mask)
                % Apply mask to radiance and error data and get subarea and surroundoing
                % incorporate mask
                radiance_band(Mask==0)=NaN;
                absolute_error(Mask==0)=NaN;
            
                %retrieve radiance and absolute error of pixels in the subarea and in the surroundings
                for j=1:length(edi_lat)
                    % subarea matrices
                    radiance_band_subarea(:,:,j)=radiance_band(:,:);
                    absolute_error_subarea(:,:,j)=absolute_error(:,:);
            
                    % set to NaN latitudes
                    [x_lim_lat,y_lim_lat]=find(latitude<lat-edi_lat(j) | latitude>lat+edi_lat(j));
                    linear_indices_lat = sub2ind(size(radiance_band_subarea(:,:,j)), x_lim_lat, y_lim_lat);
                    radiance_band_subarea(linear_indices_lat + (j-1)*numel(radiance_band_subarea(:,:,1))) = NaN;
                    absolute_error_subarea(linear_indices_lat + (j-1)*numel(absolute_error_subarea(:,:,1))) = NaN;
            
                    % set to NaN longitudes
                    [x_lim_lon,y_lim_lon]=find(longitude<lon-edi_lon(j) | longitude>lon+edi_lon(j));
                    linear_indices_lon = sub2ind(size(radiance_band_subarea(:,:,j)), x_lim_lon, y_lim_lon);
                    radiance_band_subarea(linear_indices_lon + (j-1)*numel(radiance_band_subarea(:,:,1))) = NaN;
                    absolute_error_subarea(linear_indices_lon + (j-1)*numel(absolute_error_subarea(:,:,1))) = NaN;
                           
                    % area surrounding the subarea
                    radiance_band_surroundings(:,:,j)=radiance_band(:,:);
                    radiance_band_surroundings(find(isnan(radiance_band_subarea)==0))=NaN; 
                    absolute_error_surroundings(:,:,j)=absolute_error(:,:);
                    absolute_error_surroundings(find(isnan(radiance_band_subarea)==0))=NaN;
                end
            end

            function [number_pixels_Ln, number_pixels_Ls, Ln, Ls] = calculate_parameters2(radiance_band_subarea, radiance_band_surroundings, absolute_error_subarea, absolute_error_surroundings)
                % Computes the median radiance and the number of valid pixels for subarea and surroundings of each area 
                number_of_areas = size(radiance_band_subarea, 3);
            
                % Initialize
                Ln = zeros(1, number_of_areas); Ls = zeros(1, number_of_areas); 
                number_pixels_Ln = zeros(1, number_of_areas); number_pixels_Ls = zeros(1, number_of_areas);
                % For each area  
                for i = 1:number_of_areas
                    % Remove NaN
                    radiance_subarea = radiance_band_subarea(:,:,i); radiance_subarea = radiance_subarea(~isnan(radiance_subarea));
                    radiance_surroundings = radiance_band_surroundings(:,:,i); radiance_surroundings = radiance_surroundings(~isnan(radiance_surroundings));
                    % Compute median radiance
                    Ln(i) = median(radiance_subarea);
                    Ls(i) = median(radiance_surroundings);
                    % Compute number of non-NaN pixels
                    number_pixels_Ln(i) = length(radiance_subarea);
                    number_pixels_Ls(i) = length(radiance_surroundings);
                end
            end
            function [number_pixels_Ln,number_pixels_Ls,Ln,Ls,sigma_Ln,sigma_Ls,daynight]=calculate_parameters_NaN(edi_lat)
                fclose('all');          
                number_of_areas=length(edi_lat);
                for i=1:number_of_areas
                    Ln(i)=NaN;
                    Ls(i)=NaN;           
                    number_pixels_Ln(i)=NaN;
                    number_pixels_Ls(i)=NaN;
                    sigma_Ln(i)=NaN;
                    sigma_Ls(i)=NaN;
                    daynight(i)=NaN;
                end 
            end
            function date=date_scene(data,file) %faster than importing function
                year=str2num(data(file).name(11:14));
                julianday=str2num(data(file).name(15:17));
                if year==1996 || year==2000 || year==2004 || year==2008 || year==2012 || year==2016 || year==2020 || year==2024
                    if julianday>=1 && julianday<=31; month=1;day=julianday;end     %january
                    if julianday>=32 && julianday<=60; month=2;day=julianday-31;end    %february
                    if julianday>=61 && julianday<=91; month=3;day=julianday-60;end    %march
                    if julianday>=92 && julianday<=121; month=4;day=julianday-91;end    %april
                    if julianday>=122 && julianday<=152; month=5;day=julianday-121;end    %may
                    if julianday>=153 && julianday<=182; month=6;day=julianday-152;end    %june    
                    if julianday>=183 && julianday<=213; month=7;day=julianday-182;end    %july  
                    if julianday>=214 && julianday<=244; month=8;day=julianday-213;end    %august
                    if julianday>=245 && julianday<=274; month=9;day=julianday-244;end    %september
                    if julianday>=275 && julianday<=305; month=10;day=julianday-274;end    %october
                    if julianday>=306 && julianday<=335; month=11;day=julianday-305;end    %november
                    if julianday>=336 && julianday<=366; month=12;day=julianday-335;end    %december
                else 
                    if julianday>=1 && julianday<=31; month=1;day=julianday;end     %january
                    if julianday>=32 && julianday<=59; month=2;day=julianday-31;end    %february
                    if julianday>=60 && julianday<=90; month=3;day=julianday-59;end    %march
                    if julianday>=91 && julianday<=120; month=4;day=julianday-90;end    %april
                    if julianday>=121 && julianday<=151; month=5;day=julianday-120;end    %may
                    if julianday>=152 && julianday<=181; month=6;day=julianday-151;end    %june    
                    if julianday>=182 && julianday<=212; month=7;day=julianday-181;end    %july  
                    if julianday>=213 && julianday<=243; month=8;day=julianday-212;end    %august
                    if julianday>=244 && julianday<=273; month=9;day=julianday-243;end    %september
                    if julianday>=274 && julianday<=304; month=10;day=julianday-273;end    %october
                    if julianday>=305 && julianday<=334; month=11;day=julianday-304;end    %november
                    if julianday>=335 && julianday<=365; month=12;day=julianday-334;end    %december 
                end
                timehour=str2num(data(file).name(19:20));
                timemin=str2num(data(file).name(21:22));
                date=datenum(year,month,day,timehour,timemin,0); 
            end
            function save_to_file2(date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, numberband, satellite, daynight, devlon, devlat, app)
                output_folder = app.OutputfolderpathEditField.Value;
                filetoopen_path = fullfile(output_folder, sprintf('%g_%g', devlon, devlat), sprintf('results_band31_%s.txt', satellite));
                results = fopen(filetoopen_path, 'a');
                
                % Format each array at once, keeping NaN values in line
                date_str = sprintf('%.6e\t\t', date);
                
                % Convert each array to string with proper formatting
                formatSpec = repmat('%.6e\t\t', 1, length(Ln));
                Ln_str = sprintf(formatSpec, Ln);
                
                formatSpec = repmat('%.6e\t\t', 1, length(Ls));
                Ls_str = sprintf(formatSpec, Ls);
                
                % For integer values, use %d
                formatSpec = repmat('%d\t\t', 1, length(number_pixels_Ln));
                number_pixels_Ln_str = sprintf(formatSpec, round(number_pixels_Ln));
                
                formatSpec = repmat('%d\t\t', 1, length(number_pixels_Ls));
                number_pixels_Ls_str = sprintf(formatSpec, round(number_pixels_Ls));
                
                daynight_str = sprintf('%d\r\n', daynight);
                
                % Combine into single string and write
                output_str = [date_str, Ln_str, Ls_str, number_pixels_Ln_str, number_pixels_Ls_str, daynight_str];
                fprintf(results, '%s', output_str);
                fclose(results);
            end

            function [filtered_data1, filtered_data2] = keep_matching_data(data1, identificator1, data2, identificator2) 
                [unique_identificator1, idx_data1] = unique(identificator1, 'last'); % Identify duplicate names, 'last' to retain the last occurence (the most recent)
                data1 = data1(idx_data1); %remove duplicate from data1
                [unique_identificator2, idx_data2] = unique(identificator2, 'last');
                data2 = data2(idx_data2);

                [~, idx_data1] = ismember(unique_identificator1, unique_identificator2);%identify element and position of the element that is in identificator1 and is not in identificator2
                data1(idx_data1 == 0) = [];%remove extra file from data
                [~, idx_data2] = ismember(unique_identificator2, unique_identificator1);
                data2(idx_data2 == 0) = [];

                filtered_data1 = data1;
                filtered_data2 = data2;
            end 
        
        end   
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%% SCRIPT 2 %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function startSimulation2(app)
            updateLog(app, ' ', 'info');updateLog(app, ' ', 'info');updateLog(app, ' ', 'progress');
            %% (0) Identify the folders where results from script2 are allocated (one folder for each grid point)
            % get the folder contents
            output_folder = app.OutputfolderpathEditField.Value;
            pathway= output_folder;
            d=dir(output_folder);
            % remove all files (isdir property is 0)
            dfolders=d([d(:).isdir]);
            % remove '.' and '..' and other folders not matching ROI
            dfolders=dfolders(cellfun(@(x) ~isempty(regexp(x, '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));
 
            if strcmp(app.ROIstoanalyzeDropDown.Value,'All')
                maxiter=length(dfolders);
            else
                maxiter=1;
                dfolders(1).name=app.ROIstoanalyzeDropDown.Value;
            end
            if maxiter==0
                updateLog(app, 'No ROI folders found in output directory. Run DATA PROCESSING first.', 'error');
                drawnow;
                return;
            end
            updateLog(app, 'Checking input files...', 'info');drawnow;

            reference_folder = app.ReferenceROIDropDown.Value;
            path_ref_check = fullfile(pathway, reference_folder);
            if ~isfolder(path_ref_check)
                updateLog(app, ' ', 'info');updateLog(app, ' ', 'info');updateLog(app, sprintf('Reference ROI folder not found: %s', reference_folder), 'error');
                drawnow;
                return;
            end

            %% CHECK: verify Aqua and Terra files exist before computing
            updateLog(app, 'Checking input files...', 'info');drawnow;
            aqua_missing = false;terra_missing = false;
            for i = 1:maxiter
                if ~exist(fullfile(pathway, dfolders(i).name, 'results_band31_Aqua.txt'), 'file');aqua_missing = true;end
                if ~exist(fullfile(pathway, dfolders(i).name, 'results_band31_Terra.txt'), 'file');terra_missing = true;end
            end
            if aqua_missing;updateLog(app, ' ', 'info');updateLog(app, 'Run DATA PROCESSING for MODIS/Aqua first.', 'error');drawnow;return;end
            if terra_missing;updateLog(app, ' ', 'info');updateLog(app, 'Run DATA PROCESSING for MODIS/Terra first.', 'error');drawnow;return;end
            updateLog(app, sprintf('Aqua and Terra files found for %d ROI(s). Starting...', maxiter), 'info');drawnow;
           
            for i_maxiter=1:maxiter   
                if i_maxiter==1                                            
                    updateLog(app, 'RUNNING TEMPORAL EVOLUTION', 'info');
                end
                                       
                % Delete unwanted files from gridpoints folders
                checkb=app.DeletepreviousresultsCheckBox.Value;
                if checkb==1
                    f=dir(fullfile(pathway, dfolders(i_maxiter).name, '*.txt'));f={f.name};n1=find(strcmp(f,'results_band31_Aqua.txt'));n2=find(strcmp(f,'results_band31_Terra.txt'));f(n1)=[];f(n2)=[];
                    for k=1:numel(f);delete(fullfile(pathway, dfolders(i_maxiter).name, f{k}));end 
                end
                    
                %% (1) Input parameters (daynight, interpolation type, uncertainty quantification type (determined by previous script), and choose roi, reference, and ratio pixels) 
                daynight=app.LightconditionsDropDown.Value;  %'Daylight', 'Nightlight','both'
                interpolation_type=app.InterpolationmethodDropDown.Value; % nearest, spline, linear, pchip, makima  
                years_filt=str2double(app.OrderofthelowpassfilteryearsEditField.Value); % order of the filter in years
                ratio_pixels=str2double(app.AcceptancethresholdEditField.Value);
                max_iterations=str2double(app.IterationsforuncertaintyquantificationEditField.Value); %maximum number of iterations for the bootstrapping runs generated for uncertainty quantification
                reference_folder=app.ReferenceROIDropDown.Value;%'-2_10';    
                % choose roi and reference
                side=2*[1 1.5 2 2.5 3 3.5 4 4.5 5  5.5 6  6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20 20.5 25 30 35 40];
                % Position vector -> 1  2  3  4  5  6  7  8  9  10  11 12 13 14 15 16 17 18  19  20  21  22  23  24  25  26  27  28  29  30  31  32  33  34  35  36  37  38  39  40  41 42 43 44 
                roi=find(side==str2double(app.SideROIkmDropDown.Value));
                reference=find(side==str2double(app.SidereferencekmDropDown.Value)); %42%19;%42;
            
                %% (2) Read input files, name output files,     
                data1=load(fullfile(pathway, dfolders(i_maxiter).name, 'results_band31_Aqua.txt'));data1_aux=data1;data1_aux(isnan(data1))=-999;data1_aux=unique(data1_aux,'rows');data1_aux(data1_aux==-999)="NaN";data1=data1_aux;   %delete repeated rows, if any
                data2=load(fullfile(pathway, dfolders(i_maxiter).name, 'results_band31_Terra.txt'));data2_aux=data2;data2_aux(isnan(data2))=-999;data2_aux=unique(data2_aux,'rows');data2_aux(data2_aux==-999)="NaN";data2=data2_aux;  %delete repeated rows, if any        
                path_ref_file = fullfile(pathway, reference_folder);
                data1_ref=load(fullfile(path_ref_file, 'results_band31_Aqua.txt'));data1_ref_aux=data1_ref;data1_ref_aux(isnan(data1_ref))=-999;data1_ref_aux=unique(data1_ref_aux,'rows');data1_ref_aux(data1_ref_aux==-999)="NaN";data1_ref=data1_ref_aux;  %delete repeated rows, if any
                data2_ref=load(fullfile(path_ref_file, 'results_band31_Terra.txt'));data2_ref_aux=data2_ref;data2_ref_aux(isnan(data2_ref))=-999;data2_ref_aux=unique(data2_ref_aux,'rows');data2_ref_aux(data2_ref_aux==-999)="NaN";data2_ref=data2_ref_aux; %delete repeated rows, if any
                       
                if app.InteriorButton.Value==1
                    output_file=fullfile(output_folder,dfolders(i_maxiter).name,[dfolders(i_maxiter).name '_' num2str(daynight) '_interp=' num2str(interpolation_type) '_filterType=' app.FiltertypeDropDown.Value '_cutoffPeriod=' num2str(years_filt) '_thres=' num2str(ratio_pixels) '_iter=' num2str(max_iterations) '_refroi=' reference_folder '_roi=' app.SideROIkmDropDown.Value '_ref=' app.SidereferencekmDropDown.Value '_int_results.txt']);
                    summary_analysis=fullfile(output_folder,dfolders(i_maxiter).name,[dfolders(i_maxiter).name '_' num2str(daynight) '_interp=' num2str(interpolation_type) '_filterType=' app.FiltertypeDropDown.Value '_cutoffPeriod=' num2str(years_filt) '_thres=' num2str(ratio_pixels) '_iter=' num2str(max_iterations) '_refroi=' reference_folder '_roi=' app.SideROIkmDropDown.Value '_ref=' app.SidereferencekmDropDown.Value '_int_summary.txt']);
                end
                if app.ExteriorButton.Value==1
                    output_file=fullfile(output_folder,dfolders(i_maxiter).name,[dfolders(i_maxiter).name '_' num2str(daynight) '_interp=' num2str(interpolation_type) '_filterType=' app.FiltertypeDropDown.Value '_cutoffPeriod=' num2str(years_filt) '_thres=' num2str(ratio_pixels) '_iter=' num2str(max_iterations) '_refroi=' reference_folder '_roi=' app.SideROIkmDropDown.Value '_ref=' app.SidereferencekmDropDown.Value '_ext_results.txt']);         
                    summary_analysis=fullfile(output_folder,dfolders(i_maxiter).name,[dfolders(i_maxiter).name '_' num2str(daynight) '_interp=' num2str(interpolation_type) '_filterType=' app.FiltertypeDropDown.Value '_cutoffPeriod=' num2str(years_filt) '_thres=' num2str(ratio_pixels) '_iter=' num2str(max_iterations) '_refroi=' reference_folder '_roi=' app.SideROIkmDropDown.Value '_ref=' app.SidereferencekmDropDown.Value '_ext_summary.txt']);
                end                
                                    
                %% (3) run the main function  
                updateLog(app, sprintf('Processing ROI: %s', dfolders(i_maxiter).name), 'progress');
                main_function(data1,data2,data1_ref,data2_ref,output_file,summary_analysis,daynight,interpolation_type,years_filt,roi,reference,ratio_pixels,max_iterations)
                updateLog(app, sprintf('RUNNING TEMPORAL EVOLUTION... %.1f%% of ROIs analyzed', 100*(i_maxiter)/maxiter), 'info');
            end
            
            %% functions
            function main_function(data1,data2,data1_ref,data2_ref,output_file,summary_analysis,daynight,interpolation_type,years_filt,roi,reference,ratio_pixels,max_iterations)

                %% (3.1) Load results from "script2_data_retrieval_Aqua" and "script2_data_retrieval_Terra"
                [date,Ln,Ls,number_pixels_Ln,number_pixels_Ls,uncertainty_Ln,uncertainty_Ls,flag]=loading_data(data1,data2);
                [date_ref,Ln_ref,Ls_ref,number_pixels_Ln_ref,number_pixels_Ls_ref,uncertainty_Ln_ref,uncertainty_Ls_ref,flag_ref]=loading_data(data1_ref,data2_ref);
    
                % Filter if light conditions = day or night
                if strcmp(daynight, 'Daylight') || strcmp(daynight, 'Nightlight')
                    [date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls] = filter_daynight(date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls, flag, daynight);
                    [date_ref, Ln_ref, Ls_ref, number_pixels_Ln_ref, number_pixels_Ls_ref, uncertainty_Ln_ref, uncertainty_Ls_ref] = filter_daynight(date_ref, Ln_ref, Ls_ref, number_pixels_Ln_ref, number_pixels_Ls_ref, uncertainty_Ln_ref, uncertainty_Ls_ref, flag_ref, daynight);
                end
                
                %Cleaning data --> making sure same time in all the files
                [date_ref, Ln_ref, Ls_ref, number_pixels_Ln_ref, number_pixels_Ls_ref, uncertainty_Ln_ref, uncertainty_Ls_ref, date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls] = ...
                      synchronizeData(date_ref, date, Ln_ref, Ls_ref, number_pixels_Ln_ref, number_pixels_Ls_ref, uncertainty_Ln_ref, uncertainty_Ls_ref, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls);
                
                % Set iterations based on uncertainty type
                index_max=1;
         
                moma_accumulated_ANOMALY=[];
                moma_accumulated_ANOMALY_1=[];
                moma_accumulated_ANOMALY_2=[];
                for index=1:index_max
                    %% (3.2) Calculate anomaly
                    [ANOMALY,ANOMALY_1,ANOMALY_2]=calculate_anomaly(roi,reference,ratio_pixels,Ln,Ln_ref,Ls_ref,number_pixels_Ln,number_pixels_Ln_ref,number_pixels_Ls_ref,uncertainty_Ln,uncertainty_Ls);
            
                    %% (3.3) Calculate median value of the anomaly per day
                    [day_ANOMALY,daily_ANOMALY]=d_median(date,ANOMALY);
                    [day_ANOMALY_1,daily_ANOMALY_1]=d_median(date,ANOMALY_1);
                    [day_ANOMALY_2,daily_ANOMALY_2]=d_median(date,ANOMALY_2);
            
                    %% (3.4) Interpolate to fill gaps
                    %% ANOMALY
                    day_first_ANOMALY=day_ANOMALY(1);day_end_ANOMALY=day_ANOMALY(end);
                    day_ANOMALY=day_ANOMALY(~isnan(daily_ANOMALY));
                    daily_ANOMALY=daily_ANOMALY(~isnan(daily_ANOMALY));
                        % output --> largest gap of the time series (in days)   
                    largest_gap=max(diff(day_ANOMALY)-1); 

                    % interpolation
                    [day_ANOMALY,daily_ANOMALY,ratio_daily_gaps_ANOMALY]=interpolation(day_ANOMALY,daily_ANOMALY,interpolation_type);
                        % output --> ratio of gaps (in %) and average number of days with data per year)
                    total_years = (day_ANOMALY(end) - day_ANOMALY(1)) / 365.25;
                    average_number_days_with_data_per_year_ANOMALY=round((1-ratio_daily_gaps_ANOMALY/100)*length(day_ANOMALY)/total_years); 

                    %%ANOMALY_1
                    day_first_ANOMALY_1=day_ANOMALY_1(1);day_end_ANOMALY_1=day_ANOMALY_1(end);
                    day_ANOMALY_1=day_ANOMALY_1(~isnan(daily_ANOMALY_1));
                    daily_ANOMALY_1=daily_ANOMALY_1(~isnan(daily_ANOMALY_1)); 
                    % interpolation
                    [day_ANOMALY_1,daily_ANOMALY_1,ratio_daily_gaps_ANOMALY_1]=interpolation(day_ANOMALY_1,daily_ANOMALY_1,interpolation_type);

                    %%ANOMALY_2
                    day_first_ANOMALY_2=day_ANOMALY_2(1);day_end_ANOMALY_2=day_ANOMALY_2(end);
                    day_ANOMALY_2=day_ANOMALY_2(~isnan(daily_ANOMALY_2));
                    daily_ANOMALY_2=daily_ANOMALY_2(~isnan(daily_ANOMALY_2)); 
                    % interpolation
                    [day_ANOMALY_2,daily_ANOMALY_2,ratio_daily_gaps_ANOMALY_2]=interpolation(day_ANOMALY_2,daily_ANOMALY_2,interpolation_type);                    
          
                    %% (3.5) Calculation of filtered anomaly
                    %%ANOMALY
                    order_filt=round(years_filt);
                    day_aux_ANOMALY=day_ANOMALY;
                    [day_ANOMALY,moma_ANOMALY]=mom(day_ANOMALY,daily_ANOMALY,order_filt,day_first_ANOMALY,day_end_ANOMALY);
                    moma_ANOMALY(1:order_filt)=[];day_ANOMALY(1:order_filt)=[];
                    %day_ANOMALY=day_ANOMALY-order_filt/2;               
                    moma_accumulated_ANOMALY=[moma_accumulated_ANOMALY; moma_ANOMALY]; % store in a matrix

                    %%ANOMALY_1
                    day_aux_ANOMALY_1=day_ANOMALY_1;
                    [day_ANOMALY_1,moma_ANOMALY_1]=mom(day_ANOMALY_1,daily_ANOMALY_1,order_filt,day_first_ANOMALY_1,day_end_ANOMALY_1);
                    moma_ANOMALY_1(1:order_filt)=[];day_ANOMALY_1(1:order_filt)=[];
                    %day_ANOMALY_1=day_ANOMALY_1-order_filt/2;               
                    moma_accumulated_ANOMALY_1=[moma_accumulated_ANOMALY_1; moma_ANOMALY_1]; % store in a matrix  

                    %%ANOMALY_2
                    day_aux_ANOMALY_2=day_ANOMALY_2;
                    [day_ANOMALY_2,moma_ANOMALY_2]=mom(day_ANOMALY_2,daily_ANOMALY_2,order_filt,day_first_ANOMALY_2,day_end_ANOMALY_2);
                    moma_ANOMALY_2(1:order_filt)=[];day_ANOMALY_2(1:order_filt)=[];
                    %day_ANOMALY_2=day_ANOMALY_2-order_filt/2;               
                    moma_accumulated_ANOMALY_2=[moma_accumulated_ANOMALY_2; moma_ANOMALY_2]; % store in a matrix                      
                end
            
                %%ANOMALY
                a_ANOMALY = generateSynthetic(daily_ANOMALY, day_aux_ANOMALY, order_filt, day_first_ANOMALY, day_end_ANOMALY, max_iterations);
                for i=1:length(a_ANOMALY(:,1))
                    conf = 0.02275;  % 2σ (95% confidence): Removes 2.275% from each tail
                    aux=sort(a_ANOMALY(i,:));aux(end-ceil(conf*length(aux)):end)=[];aux(1:floor(conf*length(aux)))=[];
                    down(i)=min(aux);up(i)=max(aux);
                end
                DELTA_T_ANOMALY=moma_ANOMALY;
                DELTA_T_min_ANOMALY=down;
                DELTA_T_max_ANOMALY=up;

                %%ANOMALY_1
                a_ANOMALY_1 = generateSynthetic(daily_ANOMALY_1, day_aux_ANOMALY_1, order_filt, day_first_ANOMALY_1, day_end_ANOMALY_1, max_iterations);
                for i=1:length(a_ANOMALY_1(:,1))
                    conf = 0.02275;  % 2σ (95% confidence): Removes 2.275% from each tail
                    aux=sort(a_ANOMALY_1(i,:));aux(end-ceil(conf*length(aux)):end)=[];aux(1:floor(conf*length(aux)))=[];
                    down(i)=min(aux);up(i)=max(aux);
                end
                DELTA_T_ANOMALY_1=moma_ANOMALY_1;
                DELTA_T_min_ANOMALY_1=down;
                DELTA_T_max_ANOMALY_1=up;  

                %%ANOMALY_2
                a_ANOMALY_2 = generateSynthetic(daily_ANOMALY_2, day_aux_ANOMALY_2, order_filt, day_first_ANOMALY_2, day_end_ANOMALY_2, max_iterations);
                for i=1:length(a_ANOMALY_2(:,1))
                    conf = 0.02275;  % 2σ (95% confidence): Removes 2.275% from each tail
                    aux=sort(a_ANOMALY_2(i,:));aux(end-ceil(conf*length(aux)):end)=[];aux(1:floor(conf*length(aux)))=[];
                    down(i)=min(aux);up(i)=max(aux);
                end
                DELTA_T_ANOMALY_2=moma_ANOMALY_2;
                DELTA_T_min_ANOMALY_2=down;
                DELTA_T_max_ANOMALY_2=up;                      


                %% (3.7) Save results
                save_results(output_file, day_ANOMALY, DELTA_T_ANOMALY, DELTA_T_min_ANOMALY, DELTA_T_max_ANOMALY, day_ANOMALY_1, DELTA_T_ANOMALY_1, DELTA_T_min_ANOMALY_1, DELTA_T_max_ANOMALY_1, day_ANOMALY_2, DELTA_T_ANOMALY_2, DELTA_T_min_ANOMALY_2, DELTA_T_max_ANOMALY_2)
                save_summary(summary_analysis, largest_gap, ratio_daily_gaps_ANOMALY, average_number_days_with_data_per_year_ANOMALY) 
              
            end

            function [date,Ln,Ls,number_pixels_Ln,number_pixels_Ls,uncertainty_Ln,uncertainty_Ls,flag]=loading_data(data1,data2)
                data = [data1; data2];
                [date, sort_idx] = sort(data(:,1));
                Ln = data(sort_idx, 2:45);
                Ls = data(sort_idx, 46:89);
                number_pixels_Ln = data(sort_idx, 90:133);
                number_pixels_Ls = data(sort_idx, 134:177);
                uncertainty_Ln = [];
                uncertainty_Ls = [];
                flag = data(sort_idx, 178);
            end  

            function [date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls] = filter_daynight(date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls, flag, daynight, unc_type)
                if strcmp(daynight, 'Nightlight')
                    idx = flag == 0;
                else % day
                    idx = flag == 1;
                end
                
                % Remove filtered indices
                date(idx, :) = [];
                Ln(idx, :) = [];Ls(idx, :) = [];
                number_pixels_Ln(idx, :) = [];number_pixels_Ls(idx, :) = [];
            end
            
            function [date_ref, Ln_ref, Ls_ref, number_pixels_Ln_ref, number_pixels_Ls_ref, uncertainty_Ln_ref, uncertainty_Ls_ref, date, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls] = ...
                      synchronizeData(date_ref, date, Ln_ref, Ls_ref, number_pixels_Ln_ref, number_pixels_Ls_ref, uncertainty_Ln_ref, uncertainty_Ls_ref, Ln, Ls, number_pixels_Ln, number_pixels_Ls, uncertainty_Ln, uncertainty_Ls)
                % Find matching dates and get indices for stable matching
                [~,aux1]=intersect(date_ref(:,1),date(:,1),'stable');[~,aux2]=intersect(date(:,1),date_ref(:,1),'stable');
                % Synchronize data for date_ref-related variables
                date_ref=date_ref(aux1,:);Ln_ref=Ln_ref(aux1,:);Ls_ref=Ls_ref(aux1,:);
                number_pixels_Ln_ref=number_pixels_Ln_ref(aux1,:);number_pixels_Ls_ref=number_pixels_Ls_ref(aux1,:);
                date=date(aux2,:);Ln=Ln(aux2,:);Ls=Ls(aux2,:);
                number_pixels_Ln=number_pixels_Ln(aux2,:);number_pixels_Ls=number_pixels_Ls(aux2,:);
            end
            

            function [ANOMALY,ANOMALY_1,ANOMALY_2]=calculate_anomaly(roi,reference,ratio_pixels,Ln,Ln_ref,Ls_ref,number_pixels_Ln,number_pixels_Ln_ref,number_pixels_Ls_ref,uncertainty_Ln,uncertainty_Ls)                
                ANOMALY_1=Ln(:,roi)*1e6;
                % Select reference based on user choice
                if app.ExteriorButton.Value==1;ANOMALY_2=Ls_ref(:,reference)*1e6;end
                if app.InteriorButton.Value==1;ANOMALY_2=Ln_ref(:,reference)*1e6;end 
            
                % Reject scenes if the number of pixels within the roi and the reference is <ratio_pixels*100% of the maximum number of pixels within the roi and the reference, respectively    
                ANOMALY_1(find(number_pixels_Ln(:,roi)<round(0.01*ratio_pixels*max(number_pixels_Ln(:,roi)))))=NaN; 
                if app.ExteriorButton.Value==1;ANOMALY_2(find(number_pixels_Ls_ref(:,reference)<round(0.01*ratio_pixels*max(number_pixels_Ls_ref(:,reference)))))=NaN;end
                if app.InteriorButton.Value==1;ANOMALY_2(find(number_pixels_Ln_ref(:,reference)<round(0.01*ratio_pixels*max(number_pixels_Ln_ref(:,reference)))))=NaN;end                 

                % conversion to brightness temperature
                C1=1.19e-16;
                C2=1.44e-2;
                lambda=11.03e-6;
                ANOMALY_1=C2./(lambda*log(1+C1./(lambda^5*ANOMALY_1)));
                ANOMALY_2=C2./(lambda*log(1+C1./(lambda^5*ANOMALY_2)));
                % Compute final anomaly as the temperature difference between ROI and reference
                ANOMALY=ANOMALY_1-ANOMALY_2; 
            end

            function [day, daily_ANOMALY] = d_median(date, ANOMALY)
                [unique_days, ~, day_indices] = unique(floor(date));
                day = unique_days' + 0.5;
                daily_ANOMALY = accumarray(day_indices, ANOMALY, [], @nanmedian)';

                valid_idx = ~isnan(daily_ANOMALY);day = day(valid_idx);daily_ANOMALY = daily_ANOMALY(valid_idx);
            end
            
            function [day,daily_ANOMALY,ratio_daily_gaps]=interpolation(day,daily_ANOMALY,interpolation_type)
                aux=length(day);
                daily_ANOMALY=interp1(day,daily_ANOMALY,day(1):day(end),interpolation_type,'extrap');
                day=day(1):day(end);
            
                ratio_daily_gaps=100-100*aux/length(day);    
            end
            
            function [day, moma] = mom(day, daily_ANOMALY, order_filt, day_first, day_end)
                % Apply selected filter: wavelet + median, median, high-pass, or band-pass.
                if strcmp(app.FiltertypeDropDown.Value,'Low-pass (wavelet + median)')
                    moma=daily_ANOMALY;    
                    for k=1:10
                        moma = wden(moma,'modwtsqtwolog','s','mln',10,'sym8');
                    end    
                    moma = movmedian(moma,[order_filt 0]);
                    % Interpolate and extrapolate
                    moma=interp1(day,moma,day_first:day_end,'linear','extrap'); %extrapolate to ensure that despite NaN all the gridpoints have the same length of analysis
                    day=day_first:day_end;
                    
                    day=day-order_filt/2;                     
                end

                if strcmp(app.FiltertypeDropDown.Value,'Low-pass (median)')
                    moma=daily_ANOMALY;    
                    moma = movmedian(moma,[order_filt 0]);
                    
                    % Interpolate and extrapolate
                    moma=interp1(day,moma,day_first:day_end,'linear','extrap'); %extrapolate to ensure that despite NaN all the gridpoints have the same length of analysis
                    day=day_first:day_end;
                end   

                if strcmp(app.FiltertypeDropDown.Value,'High-pass (Butterworth)')                    
                    moma=daily_ANOMALY;

                    % Parameters
                    fs = 1;              % Sampling frequency (Hz)
                    fc = 1/order_filt;     % Cutoff frequency (Hz)
                    order = 4;           % Filter order
                    
                    % Design the high-pass Butterworth filter
                    Wn = fc / (fs / 2);     % Normalized cutoff frequency (0 to 1)
                    [b, a] = butter(order, Wn, 'high'); % Filter coefficients
                    
                    % Apply the filter to the signal
                    moma = filtfilt(b, a, moma);

                    % Interpolate and extrapolate
                    moma=interp1(day,moma,day_first:day_end,'linear','extrap'); %extrapolate to ensure that despite NaN all the gridpoints have the same length of analysis
                    day=day_first:day_end;                   
                end               
                if strcmp(app.FiltertypeDropDown.Value,'Band-pass (Butterworth)')                    
                    moma=daily_ANOMALY;

                    % Parameters
                    fs = 1;                  % Sampling frequency (Hz)
                    fc_low = 1/order_filt;                % Lower cutoff frequency (Hz)
                    fc_high = 1/7;              % Upper cutoff frequency (Hz)
                    order = 3;                  % Filter order

                    % Design the band-pass Butterworth filter
                    Wn = [fc_low, fc_high] / (fs / 2); % Normalized cutoff frequencies (0 to 1)
                    [b, a] = butter(order, Wn, 'bandpass'); % Filter coefficients

                    % Apply the filter to the signal
                    moma = filtfilt(b, a, moma);

                    % Interpolate and extrapolate
                    moma=interp1(day,moma,day_first:day_end,'linear','extrap'); %extrapolate to ensure that despite NaN all the gridpoints have the same length of analysis
                    day=day_first:day_end;                      
                end  
            end
            
            function a = generateSynthetic(daily_ANOMALY, day_aux, order_filt, day_first, day_end, max_iterations)
                % Generate synthetic anomalies by resampling within a moving window, 
                % applying the selected filter, and adjusting for temporal lag.  
                a=[];
                for i=1:max_iterations
                    wind_size=30;
                    for j=(wind_size/2+1):length(daily_ANOMALY)-wind_size/2
                        synthetic_signal(j)=datasample(daily_ANOMALY((j-(wind_size/2)):(j+(wind_size/2))),1);
                    end
                    synthetic_signal(1:(wind_size/2))=synthetic_signal((wind_size/2+1));
                    synthetic_signal(end+1:length(daily_ANOMALY))=synthetic_signal(end);
            
                    [day_synthetic_signal,moma_synthetic_signal]=mom(day_aux,synthetic_signal,order_filt,day_first,day_end);
                    moma_synthetic_signal(1:order_filt)=[];day_synthetic_signal(1:order_filt)=[];
                    day_synthetic_signal=day_synthetic_signal-order_filt/2;      
            
                    a=[a  moma_synthetic_signal'];
                end
            end
            
            
            function save_results(output_file, day_ANOMALY, DELTA_T_ANOMALY, DELTA_T_min_ANOMALY, DELTA_T_max_ANOMALY, day_ANOMALY_1, DELTA_T_ANOMALY_1, DELTA_T_min_ANOMALY_1, DELTA_T_max_ANOMALY_1, day_ANOMALY_2, DELTA_T_ANOMALY_2, DELTA_T_min_ANOMALY_2, DELTA_T_max_ANOMALY_2)
                n = length(day_ANOMALY);
                min_val_ANOMALY = min(DELTA_T_min_ANOMALY);
                min_val_ANOMALY_1 = min(DELTA_T_min_ANOMALY_1);
                min_val_ANOMALY_2 = min(DELTA_T_min_ANOMALY_2);
                fid = fopen(output_file, 'w');
                if fid == -1
                    updateLog(app, sprintf('Error: Could not open file: %s', output_file), 'error');
                    return;
                end
                str1_ANOMALY = DELTA_T_ANOMALY-min_val_ANOMALY;
                str2_ANOMALY = DELTA_T_min_ANOMALY-min_val_ANOMALY;
                str3_ANOMALY = DELTA_T_max_ANOMALY-min_val_ANOMALY;
                str1_ANOMALY_1 = DELTA_T_ANOMALY_1-min_val_ANOMALY_1;
                str2_ANOMALY_1 = DELTA_T_min_ANOMALY_1-min_val_ANOMALY_1;
                str3_ANOMALY_1 = DELTA_T_max_ANOMALY_1-min_val_ANOMALY_1;
                str1_ANOMALY_2 = DELTA_T_ANOMALY_2-min_val_ANOMALY_2;
                str2_ANOMALY_2 = DELTA_T_min_ANOMALY_2-min_val_ANOMALY_2;
                str3_ANOMALY_2 = DELTA_T_max_ANOMALY_2-min_val_ANOMALY_2;                
                for i = 1:n
                    fprintf(fid, '%s\t\t%d\t\t%d\t\t%d\t\t%d\t\t%d\t\t%d\t\t%d\t\t%d\t\t%d\r\n', ...
                        datestr(day_ANOMALY(i), 'mm/dd/yyyy'), str1_ANOMALY(i), str2_ANOMALY(i), str3_ANOMALY(i), str1_ANOMALY_1(i), str2_ANOMALY_1(i), str3_ANOMALY_1(i), str1_ANOMALY_2(i), str2_ANOMALY_2(i), str3_ANOMALY_2(i));
                end
                fclose(fid);
            end
            
            function save_summary(summary_analysis, largest_gap, ratio_daily_gaps, average_number_days_with_data_per_year)
                summary = fopen(summary_analysis, 'w');
                fprintf(summary, 'Largest gap = %g days\r\n', largest_gap);
                fprintf(summary, 'Ratio of daily gaps = %g %%\r\n', ratio_daily_gaps);
                fprintf(summary, 'Average number of days with data per year = %g days\r\n', average_number_days_with_data_per_year);
                fclose(summary);
            end             
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%% SCRIPT 3 %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function startSimulation3(app)                
                %% Helper functions for reading/writing parameters file
                function write_parameters_txt(filename, param_struct)
                    % Write parameters to a text file in a readable format
                    fid = fopen(filename, 'w');
                    if fid == -1
                        updateLog(app, sprintf('Could not open file for writing: %s', filename), 'error');
                    end
                    
                    fprintf(fid, 'Animation Parameters\n');
                    fprintf(fid, '===================\n\n');
                    
                    % Write each field
                    fields = fieldnames(param_struct);
                    for i = 1:length(fields)
                        field_name = fields{i};
                        field_value = param_struct.(field_name);
                        
                        % Format the value based on its type
                        if ischar(field_value) || isstring(field_value)
                            fprintf(fid, '%s: %s\n', field_name, field_value);
                        elseif isnumeric(field_value) || islogical(field_value)
                            fprintf(fid, '%s: %g\n', field_name, field_value);
                        else
                            fprintf(fid, '%s: %s\n', field_name, mat2str(field_value));
                        end
                    end
                    
                    fclose(fid);
                end
        
                function param_struct = read_parameters_txt(filename)
                    % Read parameters from text file
                    param_struct = struct();
                    
                    fid = fopen(filename, 'r');
                    if fid == -1
                        updateLog(app, sprintf('Could not open file for reading: %s', filename), 'error');
                    end
                    
                    % Skip header lines
                    fgetl(fid); % "Animation Parameters"
                    fgetl(fid); % "==================="
                    fgetl(fid); % empty line
                    
                    % Read each parameter line
                    while ~feof(fid)
                        line = fgetl(fid);
                        if ischar(line) && contains(line, ':')
                            % Find the first colon position
                            colon_pos = strfind(line, ':');
                            if ~isempty(colon_pos)
                                % Split manually at first colon
                                field_name = strtrim(line(1:colon_pos(1)-1));
                                field_value_str = strtrim(line(colon_pos(1)+1:end));
                                
                                % Try to convert to number if possible
                                num_value = str2double(field_value_str);
                                if ~isnan(num_value)
                                    param_struct.(field_name) = num_value;
                                else
                                    % Keep as string
                                    param_struct.(field_name) = field_value_str;
                                end
                            end
                        end
                    end
                    fclose(fid);
                end
                
                function [match, mismatch_msg] = compare_parameters(params1, params2)
                    % Robust parameter comparison that handles type differences
                    match = true;
                    mismatch_msg = '';
                    
                    % Get all field names from params1
                    fields = fieldnames(params1);
                    
                    % Check if params2 has all the same fields
                    fields2 = fieldnames(params2);
                    if length(fields) ~= length(fields2)
                        match = false;
                        mismatch_msg = sprintf('Different number of parameters (%d vs %d)', length(fields), length(fields2));
                        return;
                    end
                    
                    % Compare each field
                    for i = 1:length(fields)
                        field_name = fields{i};
                        
                        % Check if field exists in params2
                        if ~isfield(params2, field_name)
                            match = false;
                            mismatch_msg = sprintf('Missing field: %s', field_name);
                            return;
                        end
                        
                        val1 = params1.(field_name);
                        val2 = params2.(field_name);
                        
                        % Handle different types of comparisons
                        if isnumeric(val1) && isnumeric(val2)
                            % Numeric comparison with tolerance
                            if abs(val1 - val2) > 1e-10
                                match = false;
                                mismatch_msg = sprintf('%s: %.10g vs %.10g', field_name, val1, val2);
                                return;
                            end
                        elseif islogical(val1) || islogical(val2)
                            % Convert both to numeric for comparison
                            if double(val1) ~= double(val2)
                                match = false;
                                mismatch_msg = sprintf('%s: %d vs %d', field_name, double(val1), double(val2));
                                return;
                            end
                        elseif ischar(val1) || isstring(val1) || ischar(val2) || isstring(val2)
                            % String comparison - convert both to char
                            str1 = char(val1);
                            str2 = char(val2);
                            if ~strcmp(str1, str2)
                                match = false;
                                mismatch_msg = sprintf('%s: "%s" vs "%s"', field_name, str1, str2);
                                return;
                            end
                        else
                            % Generic comparison for other types
                            if ~isequal(val1, val2)
                                match = false;
                                mismatch_msg = sprintf('%s: values differ (type: %s)', field_name, class(val1));
                                return;
                            end
                        end
                    end
                end
        
                %% Create wildfire mask for grid
                function mask = create_wildfire_mask(xGrid, yGrid, X_fires, Y_fires, buffer_km)
                    mask = false(size(xGrid));
                    
                    if isempty(X_fires) || isempty(Y_fires)
                        return;
                    end
                    
                    for i = 1:length(X_fires)
                        distances = sqrt((xGrid - X_fires(i)).^2 + (yGrid + Y_fires(i)).^2);
                        mask = mask | (distances <= buffer_km);
                    end
                end
                

                %% Apply wildfire exclusion to data
                function [zGrid_normalized, min_val, max_val] = normalize_excluding_wildfires(zGrid, wildfire_mask)
                    zGrid_normalized = zGrid;
                    valid_data = zGrid(~wildfire_mask);
                    
                    if isempty(valid_data) || all(isnan(valid_data))
                        min_val = min(zGrid(:));
                        max_val = max(zGrid(:));
                    else
                        min_val = min(valid_data(:));
                        max_val = max(valid_data(:));
                    end
                    
                    if max_val > min_val
                        zGrid_normalized = (zGrid - min_val) / (max_val - min_val);
                    else
                        zGrid_normalized = zeros(size(zGrid));
                    end
                end
            try
                %% Start computation               
                output_folder = app.OutputfolderpathEditField.Value;
                d = dir(output_folder);
        
                % Filter to keep only directories
                dfolders = d([d(:).isdir]);
                dfolders = dfolders(cellfun(@(x) ~isempty(regexp(x, '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));
                num_folders = numel(dfolders);
                        
                % Preallocate variables
                location = cell(num_folders, 1);
                day_start = zeros(1, num_folders);
                day_end = zeros(1, num_folders);
                results_all = cell(1, num_folders);
        
                %% Loop 1: Calculate start and end day for each folder               
                for i = 1:num_folders
                    files = dir(fullfile(output_folder, dfolders(i).name, ['*' app.ScenariotomapDropDown.Value]));
                    
                    if isempty(files)
                        [~, parentFolder] = fileparts(dfolders(i).folder);
                        updateLog(app, sprintf('Error: No files found in folder: %s/%s', parentFolder, dfolders(i).name), 'error');
                        drawnow;
                        return
                    end
                    
                    results = importdata(fullfile(output_folder, dfolders(i).name, files(1).name));
                    
                    start_date = results.textdata{1, 1};
                    end_date = results.textdata{end, 1};
                    day_start(i) = datenum(start_date, 'mm/dd/yyyy');
                    day_end(i) = datenum(end_date, 'mm/dd/yyyy');
                    
                    results_all{i} = results;
                end
                
                startday = min(day_start);
                endday = max(day_end);
                
                updateLog(app, sprintf('Data range: %s to %s', datestr(startday), datestr(endday)), 'info');
                drawnow;
        
                %% Loop 2: Adjust results data to align with start and end days
                for i = 1:num_folders
                    results = results_all{i};
                    
                    prepend_rows = day_start(i) - startday;
                    if prepend_rows > 0
                        rowCopiesStart = repmat(results.data(1, :), prepend_rows, 1);
                        results.data = [rowCopiesStart; results.data];
                    end
                    
                    append_rows = endday - day_end(i);
                    if append_rows > 0
                        rowCopiesEnd = repmat(results.data(end, :), append_rows, 1);
                        results.data = [results.data; rowCopiesEnd];
                    end
                    
                    results_all{i} = results;
                end
        
                %% Third loop: Adjust temporal references               
                results_all_locations = cell(1, num_folders);
                X = [];
                Y = [];
                
                for i = 1:num_folders
                    results = results_all{i};
                    ref_value = NaN; 
                    try
                        % Calculate reference value based on date pickers
                        start_is_empty = isempty(app.ReferencestartDatePicker.Value) || ...
                                         (isdatetime(app.ReferencestartDatePicker.Value) && isnat(app.ReferencestartDatePicker.Value));
                        end_is_empty = isempty(app.ReferenceendDatePicker.Value) || ...
                                       (isdatetime(app.ReferenceendDatePicker.Value) && isnat(app.ReferenceendDatePicker.Value));

                        if start_is_empty
                            % Option 1: No reference date specified - use first day
                            if app.AnomalyButton_2.Value==1
                                ref_value = results.data(1, 2);
                            else
                                ref_value = results.data(1, 8);
                            end
                            if i == 1
                                updateLog(app, 'Using first day as reference', 'info');
                                drawnow;
                            end
                            
                        elseif end_is_empty
                            % Option 2: Only start date specified - use that single date
                            user_date = datenum(app.ReferencestartDatePicker.Value);
                            [~, ref_index] = min(abs(datenum(results.textdata(:, 1), 'mm/dd/yyyy') - user_date));
                            
                            if app.AnomalyButton_2.Value==1
                                ref_value = results.data(ref_index, 2);
                            else
                                ref_value = results.data(ref_index, 8);
                            end
                            if i == 1
                                updateLog(app, sprintf('Using single date as reference: %s', datestr(user_date)), 'info');
                                drawnow;
                            end
                            
                        else
                            % Option 3: Both dates specified
                            start_date = datenum(app.ReferencestartDatePicker.Value);
                            end_date = datenum(app.ReferenceendDatePicker.Value);
                            
                            % Check if same date
                            if start_date == end_date
                                [~, ref_index] = min(abs(datenum(results.textdata(:, 1), 'mm/dd/yyyy') - start_date));
                                
                                if app.AnomalyButton_2.Value==1
                                    ref_value = results.data(ref_index, 2);
                                else
                                    ref_value = results.data(ref_index, 8);
                                end
                                if i == 1
                                    updateLog(app, sprintf('Using single date as reference: %s', datestr(start_date)), 'info');
                                    drawnow;
                                end
                            else
                                % Date range - use median
                                date_nums = datenum(results.textdata(:, 1), 'mm/dd/yyyy');
                                start_idx = find(date_nums >= start_date, 1, 'first');
                                end_idx = find(date_nums <= end_date, 1, 'last');
                                
                                if isempty(start_idx) || isempty(end_idx) || start_idx > end_idx
                                    updateLog(app, sprintf('Error: Invalid reference date range for location %s. Start: %s, End: %s', ...
                                        dfolders(i).name, datestr(start_date), datestr(end_date)), 'error');
                                    updateLog(app, sprintf('Available date range: %s to %s', ...
                                        datestr(date_nums(1)), datestr(date_nums(end))), 'error');
                                    drawnow;
                                    return;
                                end
                                
                                if app.AnomalyButton_2.Value==1
                                    ref_value = median(results.data(start_idx:end_idx, 2));
                                else
                                    ref_value = median(results.data(start_idx:end_idx, 8));
                                end
                                if i == 1
                                    updateLog(app, sprintf('Using median from %s to %s as reference (%d days)', ...
                                        datestr(start_date), datestr(end_date), end_idx-start_idx+1), 'info');
                                    drawnow;
                                end
                            end
                        end

                        % Check if ref_value was successfully calculated
                        if isnan(ref_value)
                            updateLog(app, sprintf('Error: Could not calculate reference value for location %s', dfolders(i).name), 'error');
                            drawnow;
                            return;
                        end
        
                        % Apply the reference
                        if app.AnomalyButton_2.Value==1
                            adjusted_data = results.data(:, 2) - ref_value;
                        else
                            adjusted_data = results.data(:, 8) - ref_value;
                        end
                        
                        results_all_locations{i} = adjusted_data;
                        
                        % Extract location coordinates
                        location{i} = dfolders(i).name;
                        aux1 = dfolders(i).name;
                        X = [X, str2double(aux1(1:(strfind(aux1, '_') - 1)))];
                        Y = [Y, str2double(aux1((strfind(aux1, '_') + 1):end))];
                        
                    catch ME
                        updateLog(app, sprintf('Error in loop %d: %s', i, ME.message), 'error');
                        drawnow;
                        return;
                    end
                end
                              
                % Extract dates for the time series
                date = results.textdata(:, 1);
                day = datenum(date, 'mm/dd/yyyy');
                
                updateLog(app, sprintf('Date range: %s to %s (%d days)', datestr(day(1)), datestr(day(end)), length(day)), 'info');
                drawnow;
                
                %% Load wildfire data
                X_fires_all = [];
                Y_fires_all = [];
                date_fires = [];
                
                if app.WildfiremaskCheckBox.Value==1                    
                    try
                        lat=app.LatitudeofthereferencelocationEditField.Value;
                        lon=app.LongitudeofthereferencelocationEditField.Value;
                        fires=readtable(fullfile(app.OutputfolderpathEditField.Value, "wildfires.csv"));
                        year_fires=fires.Year;month_fires=fires.Mo;day_fires=fires.Dy;
                        
                        for i=1:length(year_fires)
                            date_fires(i)=datenum(datestr([num2str(year_fires(i)) '-' num2str(month_fires(i)) '-' num2str(day_fires(i))],'dd-mmm-yyyy'));
                        end
                        date_fires=date_fires';
                        
                        latitude_fires=(fires.Latitude-lat)*111;
                        longitude_fires=(fires.Longitude-lon)*(pi*6371*cosd(lat)/180);
                        
                        X_fires_all = longitude_fires;
                        Y_fires_all = latitude_fires;
                        
                    catch ME
                        updateLog(app, sprintf('Warning: Could not load wildfire data: %s', ME.message), 'warning');
                        drawnow;
                    end
                end
        
                %% Thermal anomaly map generation
                date_map=find(datenum(datestr(app.SpecificdateDatePicker.Value))==datenum(day)+str2double(app.TemporalcorrectiondaysEditField.Value));
                date_map_no_correction=find(datenum(datestr(app.SpecificdateDatePicker.Value))==datenum(day));
                if isempty(date_map)
                    updateLog(app, sprintf('Selected date %s not found in data range (%s to %s).', ...
                        datestr(app.SpecificdateDatePicker.Value,'dd-mmm-yyyy'), ...
                        datestr(day(1),'dd-mmm-yyyy'), datestr(day(end),'dd-mmm-yyyy')), 'error');
                    drawnow;
                    return;
                end

                if app.animation==true
                    % Initialize with default values
                    date_map_min = 1;
                    date_map_max = length(day);
                        % Check for empty or NaT values
                    start_is_empty = isempty(app.StartDatePicker.Value) || ...
                                     (isdatetime(app.StartDatePicker.Value) && isnat(app.StartDatePicker.Value));
                    end_is_empty = isempty(app.EndDatePicker.Value) || ...
                                   (isdatetime(app.EndDatePicker.Value) && isnat(app.EndDatePicker.Value));
                    
                    % Check if custom date range is specified
                    if ~start_is_empty || ~end_is_empty
                        % Handle start date
                        if ~start_is_empty

                            try
                                start_date = datenum(app.StartDatePicker.Value);
                                
                                % Validate start_date
                                if isnan(start_date) || start_date < datenum('01-Jan-1900') || start_date > datenum('01-Jan-2100')
                                    updateLog(app, 'Error: Invalid start date format. Please provide a valid date.', 'error');
                                    drawnow;
                                    return;
                                else
                                    temp_min = find(day >= start_date, 1, 'first');
                                    
                                    if isempty(temp_min)
                                        updateLog(app, sprintf('Error: Start date %s is after all available data (latest: %s).', ...
                                            datestr(start_date, 'dd-mmm-yyyy'), datestr(day(end), 'dd-mmm-yyyy')), 'error');
                                        drawnow;
                                        return;
                                    else
                                        date_map_min = temp_min;
                                    end
                                end
                            catch ME
                                updateLog(app, sprintf('Error processing start date: %s. Please check the date format.', ME.message), 'error');
                                drawnow;
                                return;
                            end
                        end
                        
                        % Handle end date
                        if ~end_is_empty
                            try
                                end_date = datenum(app.EndDatePicker.Value);
                                
                                % Validate that end_date is reasonable
                                if isnan(end_date) || end_date < datenum('01-Jan-1900') || end_date > datenum('01-Jan-2100')
                                    updateLog(app, 'Error: Invalid end date format. Please provide a valid date.', 'error');
                                    drawnow;
                                    return;
                                else
                                    temp_max = find(day <= end_date, 1, 'last');
                                    
                                    if isempty(temp_max)
                                        updateLog(app, sprintf('Error: End date %s is before all available data (earliest: %s).', ...
                                            datestr(end_date, 'dd-mmm-yyyy'), datestr(day(1), 'dd-mmm-yyyy')), 'error');
                                        drawnow;
                                        return;
                                    else
                                        date_map_max = temp_max;
                                    end
                                end
                            catch ME
                                updateLog(app, sprintf('Error processing end date: %s. Please check the date format.', ME.message), 'error');
                                drawnow;
                                return;
                            end
                        end
                        
                        % Validate that start is before end
                        if date_map_min > date_map_max
                            updateLog(app, 'Error: Start date must be before end date', 'error');
                            drawnow;
                            return;
                        end
                        
                        % Build informative message 
                        try
                            if ~start_is_empty && ~end_is_empty
                                updateLog(app, sprintf('Using custom date range: %s to %s (%d frames)', ...
                                    datestr(day(date_map_min), 'dd-mmm-yyyy'), datestr(day(date_map_max), 'dd-mmm-yyyy'), ...
                                    date_map_max - date_map_min + 1), 'info');
                            elseif ~start_is_empty
                                updateLog(app, sprintf('Using dates from %s to end: %s (%d frames)', ...
                                    datestr(day(date_map_min), 'dd-mmm-yyyy'), datestr(day(date_map_max), 'dd-mmm-yyyy'), ...
                                    date_map_max - date_map_min + 1), 'info');
                            else
                                updateLog(app, sprintf('Using dates from start: %s to %s (%d frames)', ...
                                    datestr(day(date_map_min), 'dd-mmm-yyyy'), datestr(day(date_map_max), 'dd-mmm-yyyy'), ...
                                    date_map_max - date_map_min + 1), 'info');
                            end
                            drawnow;
                        catch ME
                            updateLog(app, sprintf('Error formatting date message: %s', ME.message), 'warning');
                            updateLog(app, sprintf('Using indices: %d to %d (%d frames)', ...
                                date_map_min, date_map_max, date_map_max - date_map_min + 1), 'info');
                            drawnow;
                        end
                    else
                        updateLog(app, sprintf('Using all available dates: %s to %s (%d frames)', ...
                            datestr(day(1), 'dd-mmm-yyyy'), datestr(day(end), 'dd-mmm-yyyy'), length(day)), 'info');
                        drawnow;
                    end
                else
                    date_map_min = date_map;
                    date_map_max = date_map;
                    updateLog(app, sprintf('Single frame mode for date: %s', datestr(day(date_map))), 'info');
                    drawnow;
                end
        
                %% Create folder for frames if animation is enabled
                frames_folder = '';
                if app.animation==true                    
                    % Generate parameter signature 
                    param_signature = struct();
                    param_signature.scenario = char(app.ScenariotomapDropDown.Value);
                    if isempty(app.ReferencestartDatePicker.Value)
                        param_signature.temporal_reference_start = 0;
                    else
                        try
                            param_signature.temporal_reference_start = datenum(app.ReferencestartDatePicker.Value);
                        catch
                            param_signature.temporal_reference_start = 0;
                        end
                    end
                    if isempty(app.ReferenceendDatePicker.Value)
                        param_signature.temporal_reference_end = 0;
                    else
                        try
                            param_signature.temporal_reference_end = datenum(app.ReferenceendDatePicker.Value);
                        catch
                            param_signature.temporal_reference_end = 0;
                        end
                    end
                    param_signature.temporal_correction = str2double(app.TemporalcorrectiondaysEditField.Value);
                    param_signature.anomaly_limit_min = str2double(app.AnomalylimitsKEditField.Value);
                    param_signature.anomaly_limit_max = str2double(app.AnomalylimitsKEditField_2.Value);
                    if isempty(app.StartDatePicker.Value) || isnat(app.StartDatePicker.Value)
                        param_signature.start_date = 'all';
                        param_signature.start_date_num = 0;
                    else
                        try
                            temp_datenum = datenum(app.StartDatePicker.Value);
                            if isnan(temp_datenum)
                                param_signature.start_date = 'all';
                                param_signature.start_date_num = 0;
                            else
                                param_signature.start_date = datestr(temp_datenum, 'yyyy-mm-dd');
                                param_signature.start_date_num = temp_datenum;
                            end
                        catch
                            param_signature.start_date = 'all';
                            param_signature.start_date_num = 0;
                        end
                    end
                    if isempty(app.EndDatePicker.Value) || isnat(app.EndDatePicker.Value)
                        param_signature.end_date = 'all';
                        param_signature.end_date_num = 0;
                    else
                        try
                            temp_datenum = datenum(app.EndDatePicker.Value);
                            if isnan(temp_datenum)
                                param_signature.end_date = 'all';
                                param_signature.end_date_num = 0;
                            else
                                param_signature.end_date = datestr(temp_datenum, 'yyyy-mm-dd');
                                param_signature.end_date_num = temp_datenum;
                            end
                        catch
                            param_signature.end_date = 'all';
                            param_signature.end_date_num = 0;
                        end
                    end
                    param_signature.total_frames = length(day);
                    param_signature.is_anomaly = double(app.AnomalyButton_2.Value);
                    param_signature.is_roi = double(app.ROIButton_2.Value);
                    param_signature.wildfire_mask = double(app.WildfiremaskCheckBox.Value);
                    param_signature.transparency = double(app.TransparencySlider.Value);
                    param_signature.resolution = str2double(app.ResolutiongridpointsEditField.Value);
                    param_signature.is_absolute = double(app.AbsoluteButton.Value);
                    param_signature.is_spatially_normalized = double(app.SpatiallynormalizedButton.Value);
                    param_signature.is_normalized_cumulative = double(app.NormalizedcumulativeButton.Value);
                    
                    % Check existing animation folders
                    a=dir(fullfile(app.OutputfolderpathEditField.Value,'AppAnimation*_frames'));
                    resume_possible = false;
                    folder_index = 0;
                    
                    % Check if we can resume from an existing folder
                    for k = 1:length(a)
                        if a(k).isdir
                            param_file = fullfile(app.OutputfolderpathEditField.Value, a(k).name, 'parameters.txt');
                            if exist(param_file, 'file')
                                prev_params = read_parameters_txt(param_file);
                                [params_match, mismatch_msg] = compare_parameters(param_signature, prev_params);
                                
                                if params_match
                                    resume_possible = true;
                                    frames_folder = fullfile(app.OutputfolderpathEditField.Value, a(k).name);
                                    folder_name = a(k).name;
                                    num_str = regexp(folder_name, 'AppAnimation(\d+)_frames', 'tokens');
                                    if ~isempty(num_str)
                                        folder_index = str2double(num_str{1}{1});
                                    end
                                    break;
                                end
                            end
                        end
                    end
                    
                    % Create new folder if no matching parameters found
                    if ~resume_possible
                        a_all=dir(fullfile(app.OutputfolderpathEditField.Value,'AppAnimation*_frames'));
                        folder_index = 0;
                        for k = 1:length(a_all)
                            if a_all(k).isdir
                                folder_name = a_all(k).name;
                                num_str = regexp(folder_name, 'AppAnimation(\d+)_frames', 'tokens');
                                if ~isempty(num_str)
                                    current_num = str2double(num_str{1}{1});
                                    folder_index = max(folder_index, current_num);
                                end
                            end
                        end
                        folder_index = folder_index + 1;
                        
                        frames_folder = fullfile(app.OutputfolderpathEditField.Value, ['AppAnimation' num2str(folder_index) '_frames']);
                        if ~exist(frames_folder, 'dir')
                            mkdir(frames_folder);
                        end
                        
                        write_parameters_txt(fullfile(frames_folder, 'parameters.txt'), param_signature);
                        
                        updateLog(app, sprintf('Creating new frames folder: AppAnimation%d_frames', folder_index), 'info');
                        drawnow;
                    else
                        existing_frames = dir(fullfile(frames_folder, 'frame_*.png'));
                        updateLog(app, sprintf('Resuming in existing folder: AppAnimation%d_frames (%d frames already exist)', folder_index, length(existing_frames)), 'info');
                        drawnow;
                    end
                end
                                
                zGrid_overlap=0;
                frame_count = 0;
                original_date_map_min = date_map_min;
                
                if app.animation==true && resume_possible
                    existing_frames = dir(fullfile(frames_folder, 'frame_*.png'));
                    if ~isempty(existing_frames)
                        frame_numbers = zeros(length(existing_frames), 1);
                        for k = 1:length(existing_frames)
                            tokens = regexp(existing_frames(k).name, 'frame_(\d+)_', 'tokens');
                            if ~isempty(tokens)
                                frame_numbers(k) = str2double(tokens{1}{1});
                            end
                        end
                        last_saved_frame = max(frame_numbers);
                        date_map_min = date_map_min + last_saved_frame;
                        frame_count = last_saved_frame;
                        
                        updateLog(app, sprintf('Resuming from frame %d', last_saved_frame + 1), 'info');
                        drawnow;
                    end
                end
        
                total_frames = date_map_max - original_date_map_min + 1;
        
                % Determine loop range for cumulative calculation
                if app.NormalizedcumulativeButton.Value==1
                    % Check if user specified a start date
                    if isempty(app.StartDatePicker.Value)
                        % No start date specified - always accumulate from day 1
                        cumulative_start = 1;
                        plot_start = date_map_min;
                        if date_map_min > 1
                            updateLog(app, sprintf('Normalized cumulative: accumulating from day 1, plotting from frame %d', date_map_min), 'info');
                            drawnow;
                        end
                    else
                        % Start date specified - accumulate from that date
                        cumulative_start = original_date_map_min;  
                        plot_start = date_map_min;  
                        updateLog(app, sprintf('Normalized cumulative: accumulating from user-selected date (frame %d)', cumulative_start), 'info');
                        drawnow;
                    end
                else
                    cumulative_start = date_map_min;
                    plot_start = date_map_min;
                end
                
                % Set up axes properties once before the loop
                if app.animation==true
                    xlimits_fixed=get(app.UIAxes, 'XLim');
                    ylimits_fixed=get(app.UIAxes, 'YLim');
                    if ~app.hasPlotted
                        xlimits_fixed = [min(X)-5, max(X)+5];  % Add 5km padding
                        ylimits_fixed = [min(Y)-5, max(Y)+5];
                        
                        % Calculate nice tick intervals
                        x_range = xlimits_fixed(2) - xlimits_fixed(1);
                        y_range = ylimits_fixed(2) - ylimits_fixed(1);
                        x_tick_spacing = round(x_range/6);
                        y_tick_spacing = round(y_range/6);
                        nice_numbers = [1, 2, 5, 10, 20, 25, 50, 100];
                        x_tick_spacing = nice_numbers(find(nice_numbers >= x_tick_spacing, 1, 'first'));
                        y_tick_spacing = nice_numbers(find(nice_numbers >= y_tick_spacing, 1, 'first'));
                        
                        % Set tick locations
                        x_tick_start = ceil(xlimits_fixed(1)/x_tick_spacing)*x_tick_spacing;
                        y_tick_start = ceil(ylimits_fixed(1)/y_tick_spacing)*y_tick_spacing;
                        
                        app.UIAxes.XTick = x_tick_start:x_tick_spacing:xlimits_fixed(2);
                        app.UIAxes.YTick = y_tick_start:y_tick_spacing:ylimits_fixed(2);
                        
                        % Format tick labels to remove unnecessary decimals
                        app.UIAxes.XTickLabel = arrayfun(@(x) sprintf('%.0f', x), app.UIAxes.XTick, 'UniformOutput', false);
                        app.UIAxes.YTickLabel = arrayfun(@(y) sprintf('%.0f', y), app.UIAxes.YTick, 'UniformOutput', false);
                    end
                    image(app.UIAxes,[-app.W_side app.E_side]/1000,[-app.S_side app.N_side]/1000,flipud(app.I));%here+
                    set(app.UIAxes,'YDir','normal')
                    set(app.UIAxes, 'Visible', 'on')
                    xlabel(app.UIAxes,'km')
                    ylabel(app.UIAxes,'km')
                    set(app.UIAxes,'XLim',xlimits_fixed)
                    set(app.UIAxes,'YLim',ylimits_fixed)
                    set(app.UIAxes,'XLimMode','manual','YLimMode','manual');
                    set(app.UIAxes,'XTickMode','manual','YTickMode','manual');
                    set(app.SSTARv10UIFigure, 'HandleVisibility', 'off');
                end
  
                % Main plotting loop
                for j=cumulative_start:1:date_map_max
                    try
                        for i=1:length(X)
                            Z(i)=results_all_locations{find(strcmp(location,[num2str(X(i)) '_' num2str(Y(i))]))}(j);
                            if Z(i)<=str2num(app.AnomalylimitsKEditField.Value) && app.AbsoluteButton.Value==1
                                Z(i)=NaN;
                            end
                        end
                        if all(isnan(Z))
                            updateLog(app, sprintf('All Z values are NaN for date %s. Lower the anomaly limit or check data.', ...
                                datestr(day(j),'dd-mmm-yyyy')), 'warning');
                            hold(app.UIAxes,'off');
                            return;
                        end
                    
                        % Interpolation
                        Z_raw = Z;
                        F = scatteredInterpolant(X',Y',Z');
                        [xmin,xmax] = bounds(X);
                        [ymin,ymax] = bounds(Y);
                        [xGrid,yGrid] = meshgrid(linspace(xmin,xmax,app.ResolutiongridpointsEditField.Value),linspace(ymin,ymax,app.ResolutiongridpointsEditField.Value));
                        zGrid = F(xGrid,yGrid);
                        zGrid_overlap=zGrid_overlap+zGrid;
                        
                        % Skip frames that shouldn't be plotted
                        if (app.animation==false && app.NormalizedcumulativeButton.Value==1 && j < date_map) || ...
                           (app.animation==true && j < plot_start)
                            if app.NormalizedcumulativeButton.Value==1 && mod(j, 100) == 0
                                updateLog(app, sprintf('Accumulating previous data: %d/%d frames', j, plot_start-1), 'info');
                                drawnow;
                            end
                            continue;
                        end
        
                        % Create wildfire mask
                        wildfire_mask = false(size(xGrid));
                        if app.WildfiremaskCheckBox.Value==1
                            fires_before_date = date_fires <= datenum(date(j),'mm/dd/yyyy');
                            X_fires = X_fires_all(fires_before_date);
                            Y_fires = Y_fires_all(fires_before_date);
                            buffer_km = 1;
                            wildfire_mask = create_wildfire_mask(xGrid, yGrid, X_fires, Y_fires, buffer_km);
                        end
        
                        % Visualize the result
                        if app.animation==true
                            image(app.UIAxes,[-app.W_side app.E_side]/1000,[-app.S_side app.N_side]/1000,app.I);
                            hold(app.UIAxes,'on')
                        else
                            xlimits=get(app.UIAxes, 'XLim');
                            ylimits=get(app.UIAxes, 'YLim');
                            
%                           If no previous plot, calculate limits from data and format ticks
                            if ~app.hasPlotted
                                xlimits = [min(X)-5, max(X)+5];  %# Add 5km padding
                                ylimits = [min(Y)-5, max(Y)+5];
                                
                                x_range = xlimits(2) - xlimits(1);
                                y_range = ylimits(2) - ylimits(1);
                                x_tick_spacing = round(x_range/6);
                                y_tick_spacing = round(y_range/6);
                                
%                               Round to nice numbers (5, 10, 20, etc.)
                                nice_numbers = [1, 2, 5, 10, 20, 25, 50, 100];
                                x_tick_spacing = nice_numbers(find(nice_numbers >= x_tick_spacing, 1, 'first'));
                                y_tick_spacing = nice_numbers(find(nice_numbers >= y_tick_spacing, 1, 'first'));

                                x_tick_start = ceil(xlimits(1)/x_tick_spacing)*x_tick_spacing;
                                y_tick_start = ceil(ylimits(1)/y_tick_spacing)*y_tick_spacing;
                                
                                app.UIAxes.XTick = x_tick_start:x_tick_spacing:xlimits(2);
                                app.UIAxes.YTick = y_tick_start:y_tick_spacing:ylimits(2);
                                app.UIAxes.XTickLabel = arrayfun(@(x) sprintf('%.0f', x), app.UIAxes.XTick, 'UniformOutput', false);
                                app.UIAxes.YTickLabel = arrayfun(@(y) sprintf('%.0f', y), app.UIAxes.YTick, 'UniformOutput', false);
                            end
                            
                            image(app.UIAxes, [-app.W_side app.E_side]/1000, [-app.S_side app.N_side]/1000, flipud(app.I));
                            set(app.UIAxes, 'YDir', 'normal')
                            set(app.UIAxes, 'Visible', 'on')
                            axis(app.UIAxes,'tight')
                            xlabel(app.UIAxes,'km')
                            ylabel(app.UIAxes,'km')
                            set(app.UIAxes,'XLim',xlimits)
                            set(app.UIAxes,'YLim',ylimits)
                            set(app.UIAxes,'XLimMode','manual','YLimMode','manual');
                            
%                           Set tick mode based on whether plot was made before
                            if ~app.hasPlotted
                                set(app.UIAxes,'XTickMode','manual','YTickMode','manual');
                            else
                                set(app.UIAxes,'XTickMode','auto','YTickMode','auto');
                            end
                            
                            hold(app.UIAxes,'on')
                        end
        
                        if app.SpatiallynormalizedButton.Value==1
                            if app.WildfiremaskCheckBox.Value==1
                                [zGrid, ~, ~] = normalize_excluding_wildfires(zGrid, wildfire_mask);
                            else
                                zGrid=(zGrid-min(min(zGrid)))/max(max(zGrid-min(min(zGrid))));
                            end
                            app.AnomalylimitsKEditField.Value="0";
                            app.AnomalylimitsKEditField_2.Value="1";
                        end
                        
                        if app.NormalizedcumulativeButton.Value==1
                            if app.WildfiremaskCheckBox.Value==1
                                [zGrid, ~, ~] = normalize_excluding_wildfires(zGrid_overlap, wildfire_mask);
                            else
                                zGrid=(zGrid_overlap-min(min(zGrid_overlap)))/max(max(zGrid_overlap-min(min(zGrid_overlap))));
                            end
                            app.AnomalylimitsKEditField.Value="0";
                            app.AnomalylimitsKEditField_2.Value="1";
                        end
        
                        if app.animation==true
                            im1=scatter(app.UIAxes,xGrid(:),-yGrid(:),[],zGrid(:),'filled','MarkerFaceAlpha', app.TransparencySlider.Value);
                        else
                            im1=scatter(app.UIAxes,xGrid(:),yGrid(:),[],zGrid(:),'filled','MarkerFaceAlpha', app.TransparencySlider.Value);
                        end
                        roiNames = arrayfun(@(x,y) sprintf('%g_%g', x, y), X(:), Y(:), 'UniformOutput', false);
                        Xcopy = X(:); Ycopy = Y(:);
                        im1.ButtonDownFcn = @(src, event) showROITooltip(app, src, event, Xcopy, Ycopy, roiNames);
    
                        caxis(app.UIAxes,[str2num(app.AnomalylimitsKEditField.Value) str2num(app.AnomalylimitsKEditField_2.Value)])
                        colormap(app.UIAxes,'jet')
                        cb = colorbar(app.UIAxes, "Location", "eastoutside", "Color", "black","Fontsize",10);
                        cb.Label.String = 'K';
                        cb.Label.Rotation = 0;
                        cb.Label.VerticalAlignment = 'top';
                        cb.Label.Position = [0.5, str2num(app.AnomalylimitsKEditField.Value), 0];
        
                        if app.animation==true
                            title(app.UIAxes,datestr(datenum(date(j),'mm/dd/yyyy')+str2double(app.TemporalcorrectiondaysEditField.Value),'dd-mmm-yyyy'));
                        else
                            title(app.UIAxes,datestr(date(date_map_no_correction),'dd-mmm-yyyy'));
                        end
        
                        % Visualize wildfires
                        if app.WildfiremaskCheckBox.Value==1
                            markerSize_km = 1;
                            ax = app.UIAxes;
                            xLim = ax.XLim;
                            yLim = ax.YLim;
                            xScale = abs(xLim(2) - xLim(1)) / ax.Position(3);
                            yScale = abs(yLim(2) - yLim(1)) / ax.Position(4);
                            scaleFactor = min(xScale, yScale);
                            markerSize = (markerSize_km / scaleFactor)^2;
                            if app.animation==true
                                scatter(app.UIAxes, X_fires, -Y_fires, markerSize, 'sk', 'MarkerFaceColor', [0 0 0]);
                            else
                                scatter(app.UIAxes, X_fires, Y_fires, markerSize, 'sk', 'MarkerFaceColor', [0 0 0]);
                            end
                        end
                        enableDefaultInteractivity(app.UIAxes);

                        % Export to CSV if requested
                        if app.animation==false && app.ExportCSVCheckBox.Value==1
                            try
                                output_folder = app.OutputfolderpathEditField.Value;
                                date_str = datestr(date(date_map_no_correction),'yyyy-mm-dd');
                                
                                total_points = numel(xGrid);
                                
                                % DOWNSAMPLING 
                                max_export_points = 5000;                                 
                                if total_points > max_export_points
                                    downsample_factor = ceil(sqrt(total_points / max_export_points));
                                    updateLog(app, sprintf('Downsampling: %dx (from %d to ~%d points)', ...
                                        downsample_factor, total_points, floor(total_points/(downsample_factor^2))), 'info');
                                    drawnow;
                                    xGrid_export = xGrid(1:downsample_factor:end, 1:downsample_factor:end);
                                    yGrid_export = yGrid(1:downsample_factor:end, 1:downsample_factor:end);
                                    zGrid_export = zGrid(1:downsample_factor:end, 1:downsample_factor:end);
                                else
                                    xGrid_export = xGrid;
                                    yGrid_export = yGrid;
                                    zGrid_export = zGrid;
                                    downsample_factor = 1;
                                end
                                
                                export_points = numel(xGrid_export);
                                updateLog(app, sprintf('Exporting %d grid points to CSV...', export_points), 'info');
                                drawnow;
                                
                                % Prepare data
                                export_data = [xGrid_export(:), yGrid_export(:), zGrid_export(:)];
                                export_data_roi = [X(:), Y(:), Z_raw(:)];

                                % Write to LOCAL temp folder
                                temp_folder = getenv('TEMP');
                                if isempty(temp_folder);temp_folder = tempdir;end
                                temp_csv = fullfile(temp_folder, sprintf('map_data_%s.csv', date_str));

                                % Delete old temp file if exists
                                if exist(temp_csv, 'file');delete(temp_csv);end
                                
                                % Write to local drive
                                fid = fopen(temp_csv, 'w');
                                if fid == -1; updateLog(app,'Could not open temp file for writing');end
                                
                                fprintf(fid, 'X_km,Y_km,Interp_K\n');
                                
                                % Write all data
                                for idx = 1:size(export_data, 1)
                                    fprintf(fid, '%.6f,%.6f,%.6f\n', ...
                                        export_data(idx, 1), export_data(idx, 2), export_data(idx, 3));
                                    
                                    if mod(idx, 10000) == 0
                                        updateLog(app, sprintf('Writing: %d/%d (%.0f%%)', ...
                                            idx, export_points, 100*idx/export_points), 'info');
                                        drawnow;
                                    end
                                end
                                
                                fclose(fid);

                                % Write raw ROI CSV
                                roi_csv = fullfile(temp_folder, sprintf('roi_data_%s.csv', date_str));
                                if exist(roi_csv, 'file');delete(roi_csv);end
                                fid_roi = fopen(roi_csv, 'w');
                                if fid_roi == -1;updateLog(app, 'Could not open ROI temp file for writing', 'error');end
                                
                                fprintf(fid_roi, 'X_km,Y_km,Raw_K\n');
                                for idx = 1:size(export_data_roi, 1)
                                    fprintf(fid_roi, '%.6f,%.6f,%.6f\n', ...
                                        export_data_roi(idx, 1), export_data_roi(idx, 2), export_data_roi(idx, 3));
                                end
                                fclose(fid_roi);
                                                                
                                % Verify local file
                                if ~exist(temp_csv, 'file'); updateLog(app, 'Temp file was not created', 'error');end
                                
                                temp_info = dir(temp_csv);
                                updateLog(app, sprintf('Temp file: %.2f MB', temp_info.bytes/(1024*1024)), 'info');
                                drawnow;
                                
                                % Copy to network drive
                                network_csv = fullfile(output_folder, sprintf('map_interp_data_%s.csv', date_str));
                                
                                % Delete old network file if exists
                                if exist(network_csv, 'file')
                                    try
                                        delete(network_csv);
                                    catch
                                        updateLog(app, 'Warning: Could not delete old file', 'warning');
                                    end
                                end
                                
                                % Copy with error handling
                                try
                                    copyfile(temp_csv, network_csv, 'f');
                                    pause(1);
                                    
                                    if exist(network_csv, 'file')
                                        net_info = dir(network_csv);
                                        updateLog(app, sprintf('SUCCESS! File exported: %.2f MB', net_info.bytes/(1024*1024)), 'info');
                                        updateLog(app, sprintf('Location: %s', network_csv), 'info');
                                    
                                        delete(temp_csv);
                                    else
                                        updateLog(app, 'File not found after copy', 'warning');
                                        updateLog(app, sprintf('File available locally: %s', temp_csv), 'warning');
                                    end
                                    
                                catch copy_err
                                    updateLog(app, sprintf('Copy failed: %s', copy_err.message), 'error');
                                    updateLog(app, sprintf('File saved locally at: %s', temp_csv), 'warning');
                                end
                                drawnow;
                                
                                % Copy ROI CSV to network drive
                                network_roi_csv = fullfile(output_folder, sprintf('map_roi_data_%s.csv', date_str));
                                if exist(network_roi_csv, 'file')
                                    try
                                        delete(network_roi_csv);
                                    catch
                                        updateLog(app, 'Warning: Could not delete old ROI file', 'warning');
                                    end
                                end
                                try
                                    copyfile(roi_csv, network_roi_csv, 'f');
                                    if exist(network_roi_csv, 'file')
                                        updateLog(app, sprintf('ROI file exported: %s', network_roi_csv), 'info');
                                        delete(roi_csv);
                                    else
                                        updateLog(app, sprintf('ROI file available locally: %s', roi_csv), 'warning');
                                    end
                                catch copy_err
                                    updateLog(app, sprintf('ROI copy failed: %s', copy_err.message), 'error');
                                end

                                % Save metadata
                                metadata_filename = fullfile(output_folder, sprintf('map_metadata_%s.txt', date_str));
                                fid_meta = fopen(metadata_filename, 'w');
                                if fid_meta ~= -1
                                    fprintf(fid_meta, 'Map Metadata\n============\n\n');
                                    fprintf(fid_meta, 'Date: %s\n', date_str);
                                    fprintf(fid_meta, 'Original Grid Resolution: %d x %d\n', size(xGrid,1), size(xGrid,2));
                                    fprintf(fid_meta, 'Export Grid Resolution: %d x %d\n', size(xGrid_export,1), size(xGrid_export,2));
                                    fprintf(fid_meta, 'Downsample Factor: %d\n', downsample_factor);
                                    fprintf(fid_meta, 'Exported Interpolated Points: %d\n', export_points);
                                    fprintf(fid_meta, 'Exported ROI Points: %d\n', length(X));
                                    fprintf(fid_meta, 'X Range: [%.2f, %.2f] km\n', min(xGrid_export(:)), max(xGrid_export(:)));
                                    fprintf(fid_meta, 'Y Range: [%.2f, %.2f] km\n', min(yGrid_export(:)), max(yGrid_export(:)));
                                    fprintf(fid_meta, 'Value Range: [%.4f, %.4f] K\n', min(zGrid_export(:)), max(zGrid_export(:)));
                                    fprintf(fid_meta, 'Scenario: %s\n', app.ScenariotomapDropDown.Value);
                                    fclose(fid_meta);
                                end
                                
                            catch ME
                                updateLog(app, sprintf('Error exporting CSV: %s', ME.message), 'error');
                                drawnow;
                            end
                        end

                        % Save frame
                        if app.animation==true
                            date_str = datestr(datenum(date(j),'mm/dd/yyyy')+str2double(app.TemporalcorrectiondaysEditField.Value),'yyyy-mm-dd');
                            frame_filename = fullfile(frames_folder, sprintf('frame_%04d_%s.png', frame_count+1, date_str));
                            drawnow;
                            exportgraphics(app.UIAxes, frame_filename, 'Resolution', 300, 'ContentType', 'image');
                            frame_count = frame_count + 1;
                            
                            if mod(frame_count, 50) == 0 || frame_count == 1
                                updateLog(app, sprintf('Progress: %d/%d frames saved (%.1f%%)', frame_count, total_frames, 100*frame_count/total_frames), 'info');
                                drawnow;
                            end
                        end
        
                        hold(app.UIAxes,'off')
                        updateLog(app, 'Map created.', 'plot');
                    catch ME
                        updateLog(app, sprintf('Error in plotting loop at frame %d: %s', j, ME.message), 'error');
                        drawnow;
                        hold(app.UIAxes,'off')
                        if app.animation==true
                            set(app.SSTARv10UIFigure, 'HandleVisibility', 'on');
                        end
                        return;
                    end
                end
                
                % Create video
                if app.animation==true
                    set(app.SSTARv10UIFigure, 'HandleVisibility', 'on');
                    
                    updateLog(app, 'All frames saved. Starting video creation...', 'info');
                    drawnow;
                    
                    frame_files = dir(fullfile(frames_folder, 'frame_*.png'));
                    [~, idx] = sort({frame_files.name});
                    frame_files = frame_files(idx);
                    
                    if isempty(frame_files)
                        updateLog(app, 'Error: No frame files found', 'error');
                        drawnow;
                        return
                    end
                    
                    updateLog(app, sprintf('Found %d frames. Creating video...', length(frame_files)), 'info');
                    drawnow;
                    
                    video_filename = fullfile(app.OutputfolderpathEditField.Value, ['AppAnimation' num2str(folder_index) '.avi']);
                    
                    if exist(video_filename, 'file')
                        updateLog(app, 'Overwriting existing video file...', 'info');
                        drawnow;
                    end
                    
                    writerObj=VideoWriter(video_filename);
                    writerObj.FrameRate = 10;
                    open(writerObj);
        
                    first_frame_path = fullfile(frames_folder, frame_files(1).name);
                    first_frame = imread(first_frame_path);
                    video_height = size(first_frame, 1);
                    video_width = size(first_frame, 2);
        
                    for i = 1:length(frame_files)
                        try
                            frame_path = fullfile(frames_folder, frame_files(i).name);
                            frame_img = imread(frame_path);
                            
                            if size(frame_img, 1) ~= video_height || size(frame_img, 2) ~= video_width
                                frame_img = imresize(frame_img, [video_height, video_width]);
                            end
        
                            writeVideo(writerObj, frame_img);
                            
                            if mod(i, 100) == 0 || i == length(frame_files)
                                updateLog(app, sprintf('Video creation: %d/%d frames (%.1f%%)', i, length(frame_files), 100*i/length(frame_files)), 'info');
                                drawnow;
                            end
                        catch ME
                            updateLog(app, sprintf('Error processing frame %d: %s', i, ME.message), 'warning');
                            drawnow;
                        end
                    end
                    
                    close(writerObj);
                    
                    updateLog(app, sprintf('Video created: %s', video_filename), 'info');
                    drawnow;
                    
                    if app.SaveindividualframesCheckBox.Value==0
                        try
                            rmdir(frames_folder, 's');
                            updateLog(app, 'Individual frames deleted', 'info');
                            drawnow;
                        catch ME
                            updateLog(app, sprintf('Could not delete frames: %s', ME.message), 'warning');
                            drawnow;
                        end
                    else
                        updateLog(app, sprintf('Frames saved in: %s', frames_folder), 'info');
                        drawnow;
                    end
                end
                
                
            catch ME
                updateLog(app, sprintf('Error during video creation: %s', ME.message), 'error');
                drawnow;
                if exist('writerObj', 'var')
                    try
                        close(writerObj);
                    catch
                    end
                end                 
            end        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0, 'DefaultUicontrolUnits', 'normalized');
            try
                shareLink = "https://drive.google.com/file/d/1cCqXm2l1ZvjpFfUV75zVakbMbimlUgtg/view?usp=sharing";
                pattern = '(?:/d/|id=)([^/&]+)';
                tokens = regexp(shareLink, pattern, 'tokens');
                fileId = tokens{1}{1};
                downloadUrl = sprintf('https://drive.google.com/uc?export=download&id=%s', fileId);
                tempFile = [tempname '.png'];
                
                % Download and display logo
                websave(tempFile, downloadUrl);
                img = imread(tempFile);
                delete(tempFile);
                app.Image.ImageSource = img;
                app.UIAxes.Visible = 'off';
            catch e
                % If loading didn't work: Create text
                textImg = uint8(255 * ones(300, 500, 3));  
                f = figure('Visible', 'off', 'Units', 'pixels', 'Position', [0 0 500 200]);
                ax = axes('Parent', f, 'Position', [0 0 1 1]);
                
                text(ax, 0.5, 0.6, 'SSTAR', ...
                    'HorizontalAlignment', 'center','FontSize', 28, 'FontWeight', 'bold','Color', 'black');
                text(ax, 0.5, 0.35, 'Subtle Surface Thermal Anomalies Recognizer', ...
                    'HorizontalAlignment', 'center','FontSize', 16,'Color', 'red');
                
                ax.Color = [1 1 1];
                axis(ax, 'off');

                frame = getframe(ax);
                textImg = frame.cdata;
                close(f);
                app.Image.ImageSource = textImg;
                app.UIAxes.Visible = 'off';
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.RUNButton.BackgroundColor = [0.8,0.8,0.8];drawnow;
            createUpdateConfigFile(app);
            startSimulation(app)
            app.RUNButton.BackgroundColor = [0.39,0.83,0.07];drawnow;
        end
        function createUpdateConfigFile(app)
            data_folder = app.DatafolderpathEditField.Value;
            output_folder = app.OutputfolderpathEditField.Value;
            lat = app.LatitudeofthereferencelocationEditField.Value;
            lon = app.LongitudeofthereferencelocationEditField.Value;
            uncertainty = 'Bootstrapping'; %app.UncertaintyquantificationapproachDropDown.Value;    
            config_data = {...
                'Data Folder', data_folder;
                'Output Path', output_folder;
                'Latitude Reference', num2str(lat);
                'Longitude Reference', num2str(lon);
                'Uncertainty Quantification Approach', uncertainty}; % Just for the user
            
            config_filename = fullfile(output_folder, 'config.txt');

            try
                fid = fopen(config_filename, 'w');
                for i = 1:size(config_data, 1)
                    fprintf(fid, '%s: %s\n', config_data{i,1}, config_data{i,2});
                end
                fclose(fid);
                updateLog(app, 'config.txt file created successfully.', 'info');
            catch ME
                updateLog(app, ['Error creating config file: ' ME.message], 'error');
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stopSimulation(app)
            app.RUNButton.BackgroundColor = [0.39,0.83,0.07];
        end

        % Value changed function: InstrumentDropDown
        function InstrumentDropDownValueChanged(app, event)
            value = app.InstrumentDropDown.Value;
        end

        % Value changed function: LatitudeofthereferencelocationEditField
        function LatitudeofthereferencelocationEditFieldValueChanged(app, event)
            value = app.LatitudeofthereferencelocationEditField.Value;
            
        end

        % Value changed function: LongitudeofthereferencelocationEditField
        function LongitudeofthereferencelocationEditFieldValueChanged(app, event)
            value = app.LongitudeofthereferencelocationEditField.Value;           
        end

        % Value changed function: CoordinatesSNkmEditField
        function CoordinatesSNkmEditFieldValueChanged(app, event)
            value = app.CoordinatesSNkmEditField.Value;            
        end

        % Value changed function: CoordinatesWEkmEditField
        function CoordinatesWEkmEditFieldValueChanged(app, event)
            value = app.CoordinatesWEkmEditField.Value;            
        end

        % Value changed function: DeleteexistingoutputfilesDropDown
        function DeleteexistingoutputfilesDropDownValueChanged(app, event)
            value = app.DeleteexistingoutputfilesDropDown.Value;           
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: PlotareaofanalysisButton, 
        % PlotareaofanalysisButton_2, PlotareaofanalysisButton_3
        function PlotareaofanalysisButtonPushed(app, event)
            updateLog(app, ' ', 'info');updateLog(app, ' ', 'info');updateLog(app, ' ', 'progress');
            updateLog(app, 'Plotting area of analysis ...', 'plot');
            switchToPlotMode(app, true);
            colorbar(app.UIAxes, 'off');
            lat = app.LatitudeofthereferencelocationEditField.Value;
            lon = app.LongitudeofthereferencelocationEditField.Value;
        
            % Create a temporary figure and geoaxes (hidden)
            tempFig = uifigure('Visible', 'off'); % Invisible figure
            gx = geoaxes(tempFig); % Create geoaxes 
        
            % Define limits and set the basemap
            geolimits(gx, [lat-0.6, lat+0.6], [lon-0.6, lon+0.6]);
            geobasemap(gx, 'satellite');  %"colorterrain"  "grayterrain" "openstreetmap" "["USGSImageryOnly" "USGSImageryTopo" "USGSTopo" "USGSHydroCached"];"
            
            [real_latitudeLimits, real_longitudeLimits] = geolimits(gx);
            wgs84 = referenceEllipsoid('WGS84', 'm');
            
            app.W_side = distance(real_latitudeLimits(1), real_longitudeLimits(1), real_latitudeLimits(1), lon, wgs84);
            app.E_side = distance(real_latitudeLimits(1), real_longitudeLimits(2), real_latitudeLimits(1), lon, wgs84);
            app.S_side = distance(real_latitudeLimits(1), real_longitudeLimits(1), lat, real_longitudeLimits(1), wgs84);
            app.N_side = distance(real_latitudeLimits(2), real_longitudeLimits(1), lat, real_longitudeLimits(1), wgs84);

            gx.Position = gx.OuterPosition;
            tempFig.Units = 'pixels';
            
            % Export and load with higher resoluton
            exportgraphics(gx, 'temp_map.png', 'Resolution', 600, 'ContentType', 'image');  % Added high resolution
            close(tempFig);
        
            app.I = imread('temp_map.png');
            
            % Clear current plot
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            
            % Plot with correct scaling
            image(app.UIAxes, [-app.W_side app.E_side]/1000, [app.N_side -app.S_side]/1000, app.I);
            
            % Set axes
            hold(app.UIAxes, 'off');
            axis(app.UIAxes, 'tight');
            xlabel(app.UIAxes, 'km');
            ylabel(app.UIAxes, 'km');
            title(app.UIAxes, 'Satellite View of Study Area');

            delete('temp_map.png');
            app.hasPlotted = true;
            updateLog(app, ' ', 'info'); 
            updateLog(app, 'Area of analysis plotted.', 'plot');
        end

        % Button pushed function: PlotcenterROIsButton
        function PlotcenterROIsButtonPushed(app, event)
            updateLog(app, 'Plotting ROIs ...', 'plot');
            switchToPlotMode(app, true);
            colorbar(app.UIAxes, 'off');
            xlimits=get(app.UIAxes, 'XLim');
            ylimits=get(app.UIAxes, 'YLim');
 
            devlat_vector = str2num(app.CoordinatesSNkmEditField.Value);
            devlon_vector = str2num(app.CoordinatesWEkmEditField.Value);
            rois_selection = app.ROIsselectionDropDown.Value;
            
            % Clear and replot image
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            image(app.UIAxes, [-app.W_side app.E_side]/1000, [app.N_side -app.S_side]/1000, app.I);
            
            % Create grid or specific locations based on selection
            if strcmp(rois_selection, 'Grid')
                [X, Y] = meshgrid(devlon_vector, devlat_vector);
            else % 'Specific locations'
                X = devlon_vector;
                Y = devlat_vector;
            end
            
%             p=plot(app.UIAxes, X, Y, '.r', 'MarkerSize', 10);
            p = scatter(app.UIAxes, X(:), Y(:), 10, 'r', 'filled');
            roiNames = arrayfun(@(x,y) sprintf('%g_%g', x, y), X(:), Y(:), 'UniformOutput', false);
            Xcopy = X(:); Ycopy = Y(:);
            p.ButtonDownFcn = @(src, event) showROITooltip(app, src, event, Xcopy, Ycopy, roiNames);
    
            % Set axes 
            axis(app.UIAxes, 'tight');
            xlabel(app.UIAxes, 'km');
            ylabel(app.UIAxes, 'km');
            set(app.UIAxes,'XLim',xlimits)
            set(app.UIAxes,'YLim',ylimits)     
            title(app.UIAxes, 'ROIs center');
            hold(app.UIAxes, 'off');
            Xall = X(:); Yall = Y(:);
            roiNames = arrayfun(@(x,y) sprintf('%g_%g', x, y), Xall, Yall, 'UniformOutput', false);
            app.UIAxes.ButtonDownFcn = @(src, event) showROITooltip(app, src, event, Xall, Yall, roiNames);
            updateLog(app, ' ', 'info');
            updateLog(app, 'ROIs plotted.', 'plot');
            enableDefaultInteractivity(app.UIAxes);
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            app.RUNButton_2.BackgroundColor = [0.8,0.8,0.8];
            startSimulation2(app)
            app.RUNButton_2.BackgroundColor = [0.39,0.83,0.07];
        end

        % Value changed function: LightconditionsDropDown
        function LightconditionsDropDownValueChanged(app, event)
            value = app.LightconditionsDropDown.Value;
            
        end

        % Value changed function: InterpolationmethodDropDown
        function InterpolationmethodDropDownValueChanged(app, event)
            value = app.InterpolationmethodDropDown.Value;
            
        end

        % Value changed function: OrderofthelowpassfilteryearsEditField
        function OrderofthelowpassfilteryearsEditFieldValueChanged(app, event)
            value = app.OrderofthelowpassfilteryearsEditField.Value;
            
        end

        % Value changed function: AcceptancethresholdEditField
        function AcceptancethresholdEditFieldValueChanged(app, event)
            value = app.AcceptancethresholdEditField.Value;
            
        end

        % Value changed function: 
        % IterationsforuncertaintyquantificationEditField
        function IterationsforuncertaintyquantificationEditFieldValueChanged(app, event)
            value = app.IterationsforuncertaintyquantificationEditField.Value;
            
        end

        % Callback function
        function ReferenceROIEditFieldValueChanged(app, event)
            value = app.ReferenceROIEditField.Value;
            
        end

        % Value changed function: SideROIkmDropDown
        function SideROIkmDropDownValueChanged(app, event)
            value = app.SideROIkmDropDown.Value;
            
        end

        % Value changed function: SidereferencekmDropDown
        function SidereferencekmDropDownValueChanged(app, event)
            value = app.SidereferencekmDropDown.Value;
            
        end

        % Value changed function: ROIstoanalyzeDropDown
        function ROIstoanalyzeDropDownValueChanged(app, event)
            value = app.ROIstoanalyzeDropDown.Value;
        end

        % Drop down opening function: ROIstoanalyzeDropDown
        function ROIstoanalyzeDropDownOpening(app, event)
            updateLog(app, 'Loading...', 'info');

            d = dir(app.OutputfolderpathEditField.Value);
            dfolders = d([d(:).isdir]);
                        
            % Filter folders starting with numbers or '-'
            validFolders = dfolders(cellfun(@(x) ~isempty(regexp(x,  '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));  
            for i = 1:length(validFolders)
                app.ROIstoanalyzeDropDown.Items{i+1} = validFolders(i).name;
            end
            updateLog(app, ' ', 'info');  
        end

        % Value changed function: ReferenceROIDropDown
        function ReferenceROIDropDownValueChanged(app, event)
            value = app.ReferenceROIDropDown.Value;
            
        end

        % Drop down opening function: ReferenceROIDropDown
        function ReferenceROIDropDownOpening(app, event)
            d = dir(app.OutputfolderpathEditField.Value);
            dfolders = d([d(:).isdir]);
            
            % Filter folders starting with numbers or '-'
            validFolders = dfolders(cellfun(@(x) ~isempty(regexp(x,  '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));
            
            for i = 1:length(validFolders)
                app.ReferenceROIDropDown.Items{i+1} = validFolders(i).name;
            end
        end

        % Value changed function: ROItoplotDropDown
        function ROItoplotDropDownValueChanged(app, event)
            value = app.ROItoplotDropDown.Value;
            
        end

        % Drop down opening function: ROItoplotDropDown
        function ROItoplotDropDownOpening(app, event)
            d = dir(app.OutputfolderpathEditField.Value);
            dfolders = d([d(:).isdir]);
            
            % Filter folders starting with numbers or '-'
            validFolders = dfolders(cellfun(@(x) ~isempty(regexp(x,  '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));
            
            for i = 1:length(validFolders)
                app.ROItoplotDropDown.Items{i+1} = validFolders(i).name;
            end
        end

        % Value changed function: ScenariotoplotDropDown
        function ScenariotoplotDropDownValueChanged(app, event)
            value = app.ScenariotoplotDropDown.Value;
            
        end

        % Drop down opening function: ScenariotoplotDropDown
        function ScenariotoplotDropDownOpening(app, event)
            app.ScenariotoplotDropDown.Items={};
            app.ScenariotoplotDropDown.Items{1}='----';
            pathway=fullfile(app.OutputfolderpathEditField.Value, app.ROItoplotDropDown.Value);
            files=dir(fullfile(pathway, '*results.txt'));
            for i=1:length(files)
                app.ScenariotoplotDropDown.Items{i+1}=files(i).name;
            end           
        end

        % Button pushed function: PlottimeseriesButton
        function PlottimeseriesButtonPushed(app, event)
            function setXLimits(axHandle)
                % Get current limits and convert to date strings for display
                currentMin = datestr(axHandle.XLim(1), 'dd-mm-yyyy');
                currentMax = datestr(axHandle.XLim(2), 'dd-mm-yyyy');
                
                answer = inputdlg({'Min X (dd-mm-yyyy or number):', 'Max X (dd-mm-yyyy or number):'}, ...
                    'Set X-Axis Limits', 1, {currentMin, currentMax});
                
                if ~isempty(answer)
                    try
                        try
                            minVal = datenum(answer{1}, 'dd-mm-yyyy');
                        catch
                            % If date parsing fails, try as numeric
                            minVal = str2double(answer{1});
                        end
                        
                        try
                            maxVal = datenum(answer{2}, 'dd-mm-yyyy');
                        catch
                            % If date parsing fails, try as numeric
                            maxVal = str2double(answer{2});
                        end
                        
                        xlim(axHandle, [minVal, maxVal]);
                    catch
                        updateLog(app, 'Invalid input. Use date format dd-mm-yyyy or numeric value', 'plot');
                    end
                end
            end
            function setYLimits(ax)
                answer = inputdlg({'Min Y:', 'Max Y:'}, 'Set Y-Axis Limits', 1, ...
                    {num2str(ax.YLim(1)), num2str(ax.YLim(2))});
                if ~isempty(answer)
                    try
                        ylim(ax, [str2double(answer{1}), str2double(answer{2})]);
                    catch
                        updateLog(app, 'Invalid input for Y-axis limits', 'plot');
                    end
                end
            end
            function toggleGrid(axHandle)
                if strcmp(axHandle.XGrid, 'off')
                    axHandle.XGrid = 'on';
                    axHandle.YGrid = 'on';
                else
                    axHandle.XGrid = 'off';
                    axHandle.YGrid = 'off';
                end
            end

            updateLog(app, 'Plotting time series in an external window ...', 'plot');
            if strcmp(app.ScenariotoplotDropDown.Value, '----')
                updateLog(app, ' ', 'info');
                updateLog(app, 'No scenario to plot selected', 'error');
                return;
            end

            pathway=fullfile(app.OutputfolderpathEditField.Value,app.ROItoplotDropDown.Value,app.ScenariotoplotDropDown.Value);
            results=importdata(pathway);
            
            date=results.textdata(:,1);
            day=datenum(date,'mm/dd/yyyy')+str2double(app.TemporalcorrectiondaysEditField_2.Value);

            if app.AnomalyButton.Value==1
                anomaly=results.data(:,2);
                DELTA_T_min=results.data(:,4); %note that the code reads data in columns 4 and 6, not 2 and 3 and they appear in the file
                DELTA_T_max=results.data(:,6);
                ylab='$$\overline{\delta T}\,\,\,\,(K)$$';
                tit="Anomaly";
            end
  
            if app.ROIButton.Value==1
                anomaly=results.data(:,8);
                DELTA_T_min=results.data(:,10); 
                DELTA_T_max=results.data(:,12);
                ylab='$(K)$';
                tit="ROI";
            end                 
            if app.REFButton.Value==1
                anomaly=results.data(:,14);
                DELTA_T_min=results.data(:,16); 
                DELTA_T_max=results.data(:,18);
                ylab='$(K)$';
                tit="Reference";
            end                 
                       
            DELTA_T=anomaly-min(DELTA_T_min);
            DELTA_T_max=DELTA_T_max-min(DELTA_T_min);
            DELTA_T_min=DELTA_T_min-min(DELTA_T_min);
                 
            % Export data to CSV if checkbox is enabled
            if app.ExportdatatocsvCheckBox.Value == 1
                try
                    % Create filename with ROI, Scenario, and temporal correction
                    temp_correction = app.TemporalcorrectiondaysEditField_2.Value;
                    csv_filename = sprintf('%s_temp%s_timeseries.csv', ...
                        app.ScenariotoplotDropDown.Value, ...
                        temp_correction);
                    
                    % Full path for CSV file (same directory as the data file)
                    csv_path = fullfile(app.OutputfolderpathEditField.Value, ...
                        app.ROItoplotDropDown.Value, csv_filename);
                    
                    % Prepare data table
                    export_table = table(date, DELTA_T, DELTA_T_min, DELTA_T_max, ...
                        'VariableNames', {'Date', 'Median_Anomaly_K', 'Lower_Envelope_K', 'Upper_Envelope_K'});
                    
                    % Write to CSV
                    writetable(export_table, csv_path);
                    
                    updateLog(app, ['Time series data exported.'], 'info');
                catch ME
                    updateLog(app, ['Error exporting CSV: ' ME.message], 'error');
                end
            end

            % Plot data
            hFig=uifigure;
            ax = uiaxes(hFig);
            % Create context menu for axis modification
            cmenu = uicontextmenu(hFig);
            ax.ContextMenu = cmenu;
            
            % Add menu items
            uimenu(cmenu, 'Text', 'Set X-Axis Limits...', 'MenuSelectedFcn', @(src,event) setXLimits(ax));
            uimenu(cmenu, 'Text', 'Set Y-Axis Limits...', 'MenuSelectedFcn', @(src,event) setYLimits(ax));
            uimenu(cmenu, 'Text', 'Toggle Grid', 'MenuSelectedFcn', @(src,event) toggleGrid(ax));
            uimenu(cmenu, 'Text', 'Auto Scale', 'MenuSelectedFcn', @(src,event) axis(ax, 'auto'));

            set(hFig, 'Position', [400 200 800 400],'Color',[1 1 1])
            set(ax, 'Position', [1 1 790 390])
            
            plot(ax,day',DELTA_T','r')
            if app.ReferencedatesCheckBox.Value==1 
                if exist('reference_dates.csv', 'file')
                    try
                        ref_date_table = readtable('reference_dates.csv');
                        ref_date1=ref_date_table{:,1};ref_date2=ref_date_table{:,2};
                        if ~isdatetime(ref_date1)
                            ref_date1 = datetime(ref_date1, 'InputFormat', 'yyyy-MM-dd'); 
                            ref_date2 = datetime(ref_date2, 'InputFormat', 'yyyy-MM-dd');
                        end
                        hold(ax,'on')
                        y1=ylim(ax);
                        fill(ax,[datenum(ref_date1) datenum(ref_date2) datenum(ref_date2) datenum(ref_date1)], [0 0 max(DELTA_T_max) max(DELTA_T_max)], [0 0.2 0.4], 'FaceAlpha', 0.3,'EdgeColor',[0.6 0.8 1])                   
                        updateLog(app, ' ', 'info');
                    catch ME
                        updateLog(app, ['Error loading reference file: ' ME.message], 'error');
                    end
                else
                    updateLog(app, 'reference_dates.csv file does not exist, plot without it', 'warning');
                end
            end    

            patch(ax,[day', fliplr(day')], [DELTA_T_min', fliplr(DELTA_T_max')], 'r','EdgeColor','none')
            alpha(ax,0.1)           
  
            year_max=datevec(max(day));year_max=year_max(1);
            year_min=datevec(min(day));year_min=year_min(1);            
            year_ticks=(year_min+1):ceil((year_max-year_min)/5):year_max;
            year_labels{:}=num2str(year_ticks(:));
            xticks(ax,datenum(year_ticks',ones(length(year_ticks),1),ones(length(year_ticks),1)));  %ticks at January 1st
            xticklabels(ax,year_labels)
            ylabel(ax,ylab,'Interpreter','Latex','FontWeight','bold');
            set(ax,'xlim',[min(day) max(day)])
            set(ax,'FontSize',16)  
            title(ax, tit);
            %updateLog(app, ' ', 'info');
            updateLog(app, 'Time series plotted in an external window.', 'plot');

        end

        % Value changed function: DeletepreviousresultsCheckBox
        function DeletepreviousresultsCheckBoxValueChanged(app, event)
            value = app.DeletepreviousresultsCheckBox.Value;            
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            changingValue = app.TransparencySlider.Value;
            app.EditField.Value = changingValue * 100;
            
        end

        % Button pushed function: PlotsetupButton
        function PlotsetupButtonPushed(app, event)
            updateLog(app, ' ', 'info');updateLog(app, ' ', 'info');updateLog(app, ' ', 'progress');
            updateLog(app, 'Plotting setup ...', 'plot');
            switchToPlotMode(app, true);
            colorbar(app.UIAxes, 'off');
            xlimits=get(app.UIAxes, 'XLim');
            ylimits=get(app.UIAxes, 'YLim');    
            
            % Plot base image
            image(app.UIAxes,[-app.W_side app.E_side]/1000,[app.N_side -app.S_side]/1000,app.I);
            set(app.UIAxes,'YDir','normal')
            set(app.UIAxes, 'Visible', 'on')
            axis(app.UIAxes,'tight')
            xlabel(app.UIAxes,'km')
            ylabel(app.UIAxes,'km')
            set(app.UIAxes,'XLim',xlimits)
            set(app.UIAxes,'YLim',ylimits)            
            hold(app.UIAxes,'on')
                          
            Xall = [];
            Yall = [];
            allNames = {};

            if strcmp(app.ROIstoanalyzeDropDown.Value,'All')==1
                d = dir(app.OutputfolderpathEditField.Value);
                dfolders = d([d(:).isdir]);
                dfolders = dfolders(cellfun(@(x) ~isempty(regexp(x,  '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));

                X = []; Y = []; validFolderIdx = 1;
                
                for i = 1:length(dfolders)
                    folderName = dfolders(i).name;
                    underscorePos = strfind(folderName, '_');
                    
                    % Only process if folder name contains underscore
                    if ~isempty(underscorePos)
                        try
                            xVal = str2num(folderName(1:underscorePos(1)-1));
                            yVal = str2num(folderName(underscorePos(1)+1:end));
                            
                            % Only add if both values are valid numbers
                            if ~isempty(xVal) && ~isempty(yVal)
                                X(validFolderIdx) = xVal;
                                Y(validFolderIdx) = yVal;
                                validFolderIdx = validFolderIdx + 1;
                            end
                        catch
                            % Skip this folder if there's any error in conversion
                            continue
                        end
                    end
                end
        
                % Only proceed if we found valid coordinates
                if ~isempty(X) && ~isempty(Y)
%                   plot(app.UIAxes, X, Y, '.b', 'MarkerSize', 10);
                    pl = scatter(app.UIAxes, X(:), Y(:), 10, 'b', 'filled');
                    pl.HitTest = 'off';

                    aux1 = X-str2num(app.SideROIkmDropDown.Value)/2;
                    aux2 = X+str2num(app.SideROIkmDropDown.Value)/2;
                    aux3 = Y-str2num(app.SideROIkmDropDown.Value)/2;
                    aux4 = Y+str2num(app.SideROIkmDropDown.Value)/2;  
                    
                    for i=1:length(X)
                        patch(app.UIAxes,[aux1(i) aux1(i) aux2(i) aux2(i) aux1(i)],...
                              [aux3(i) aux4(i) aux4(i) aux3(i) aux3(i)],'b','FaceAlpha',0.5,'Edgecolor','none', 'HitTest','off');
                    end
                    roiLabels = arrayfun(@(x,y) sprintf('ROI: %g_%g', x, y), X(:), Y(:), 'UniformOutput', false);
                    Xall = [Xall; X(:)];
                    Yall = [Yall; Y(:)];
                    allNames = [allNames; roiLabels];
                end
                
                if app.InteriorButton.Value==1
                    Xref=str2num(app.ReferenceROIDropDown.Value(1:strfind(app.ReferenceROIDropDown.Value,'_')-1));
                    Yref=str2num(app.ReferenceROIDropDown.Value(strfind(app.ReferenceROIDropDown.Value,'_')+1:end));
                    aux1ref=Xref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux2ref=Xref+str2num(app.SidereferencekmDropDown.Value)/2;
                    aux3ref=Yref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux4ref=Yref+str2num(app.SidereferencekmDropDown.Value)/2;  
                    p_ref = plot(app.UIAxes,Xref,Yref,'.g','MarkerSize',10);
                    p_ref.HitTest = 'off';
                    patch(app.UIAxes,[aux1ref aux1ref aux2ref aux2ref aux1ref],[aux3ref aux4ref aux4ref aux3ref aux3ref],'g','FaceAlpha',0.3,'Edgecolor','none', 'HitTest','off');     
                end
                
                if app.ExteriorButton.Value==1
                    Xref=str2num(app.ReferenceROIDropDown.Value(1:strfind(app.ReferenceROIDropDown.Value,'_')-1));
                    Yref=str2num(app.ReferenceROIDropDown.Value(strfind(app.ReferenceROIDropDown.Value,'_')+1:end));
                    aux1ref=Xref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux2ref=Xref+str2num(app.SidereferencekmDropDown.Value)/2;
                    aux3ref=Yref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux4ref=Yref+str2num(app.SidereferencekmDropDown.Value)/2;  
                    p_ref2 = plot(app.UIAxes,Xref,Yref,'.g','MarkerSize',10);
                    p_ref.HitTest = 'off';
                    patch(app.UIAxes,[Xref-40 Xref-40 Xref+40 Xref+40 Xref-40 aux1ref aux1ref aux2ref aux2ref aux1ref],[Yref-40 Yref+40 Yref+40 Yref-40 Yref-40 aux3ref aux4ref aux4ref aux3ref aux3ref],'g','FaceAlpha',0.3,'Edgecolor','none','HitTest','off'); 
                end
                Xall = [Xall; Xref];
                Yall = [Yall; Yref];
                allNames = [allNames; {sprintf('REF: %g_%g', Xref, Yref)}];
            end
            
            if strcmp(app.ROIstoanalyzeDropDown.Value,'All')==0
                d = dir(app.OutputfolderpathEditField.Value);
                dfolders = d([d(:).isdir]);
                dfolders = dfolders(cellfun(@(x) ~isempty(regexp(x,  '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));
                
                X = []; Y = []; validFolderIdx = 1;
                
                for i = 1:length(dfolders)
                    folderName = dfolders(i).name;
                    underscorePos = strfind(folderName, '_');
                    
                    % Only process if folder name contains underscore
                    if ~isempty(underscorePos)
                        try
                            xVal = str2num(folderName(1:underscorePos(1)-1));
                            yVal = str2num(folderName(underscorePos(1)+1:end));
                            
                            % Only add if both values are valid numbers
                            if ~isempty(xVal) && ~isempty(yVal)
                                X(validFolderIdx) = xVal;
                                Y(validFolderIdx) = yVal;
                                validFolderIdx = validFolderIdx + 1;
                            end
                        catch
                            % Skip this folder if there's any error in conversion
                            continue
                        end
                    end
                end
        
                % Only proceed if we found valid coordinates
                if ~isempty(X) && ~isempty(Y)
%                     plot(app.UIAxes,X,Y,'.r','MarkerSize',10)
                    pt = scatter(app.UIAxes, X(:), Y(:), 10, 'r', 'filled');
                    pt.HitTest = 'off';
                    roiLabels = arrayfun(@(x,y) sprintf('ROI: %g_%g', x, y), X(:), Y(:), 'UniformOutput', false);
                    Xall = [Xall; X(:)];
                    Yall = [Yall; Y(:)];
                    allNames = [allNames; roiLabels];

                    % Process selected ROI
                    try
                        roiStr = app.ROIstoanalyzeDropDown.Value;
                        underscorePos = strfind(roiStr, '_');
                        if ~isempty(underscorePos)
                            X2 = str2num(roiStr(1:underscorePos(1)-1));
                            Y2 = str2num(roiStr(underscorePos(1)+1:end));
                            
                            if ~isempty(X2) && ~isempty(Y2)
                                p_roi = plot(app.UIAxes,X2,Y2,'.b','MarkerSize',10);
                                p_roi.HitTest = 'off';
                                aux1 = X2-str2num(app.SideROIkmDropDown.Value)/2;
                                aux2 = X2+str2num(app.SideROIkmDropDown.Value)/2;
                                aux3 = Y2-str2num(app.SideROIkmDropDown.Value)/2;
                                aux4 = Y2+str2num(app.SideROIkmDropDown.Value)/2;  
                                patch(app.UIAxes,[aux1 aux1 aux2 aux2 aux1],...
                                      [aux3 aux4 aux4 aux3 aux3],'b','FaceAlpha',0.3,'Edgecolor','none','HitTest','off');
                            end
                        end
                    catch
                        % Handle error in processing selected ROI
                        updateLog(app, 'Error processing selected ROI', 'warning');
                    end
                end

                if app.InteriorButton.Value==1
                    Xref=str2num(app.ReferenceROIDropDown.Value(1:strfind(app.ReferenceROIDropDown.Value,'_')-1));
                    Yref=str2num(app.ReferenceROIDropDown.Value(strfind(app.ReferenceROIDropDown.Value,'_')+1:end));
                    aux1ref=Xref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux2ref=Xref+str2num(app.SidereferencekmDropDown.Value)/2;
                    aux3ref=Yref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux4ref=Yref+str2num(app.SidereferencekmDropDown.Value)/2;  
                    p3_ref = plot(app.UIAxes,Xref,Yref,'.g','MarkerSize',10);
                    p3_ref.HitTest = 'off';
                    patch(app.UIAxes,[aux1ref aux1ref aux2ref aux2ref aux1ref],[aux3ref aux4ref aux4ref aux3ref aux3ref],'g','FaceAlpha',0.3,'Edgecolor','none','HitTest','off');   
                end
                
                if app.ExteriorButton.Value==1
                    Xref=str2num(app.ReferenceROIDropDown.Value(1:strfind(app.ReferenceROIDropDown.Value,'_')-1));
                    Yref=str2num(app.ReferenceROIDropDown.Value(strfind(app.ReferenceROIDropDown.Value,'_')+1:end));
                    aux1ref=Xref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux2ref=Xref+str2num(app.SidereferencekmDropDown.Value)/2;
                    aux3ref=Yref-str2num(app.SidereferencekmDropDown.Value)/2;
                    aux4ref=Yref+str2num(app.SidereferencekmDropDown.Value)/2;  
                    pp_ref = plot(app.UIAxes,Xref,Yref,'.g','MarkerSize',10);
                    pp_ref.HitTest = 'off';
                    patch(app.UIAxes,[Xref-40 Xref-40 Xref+40 Xref+40 Xref-40 aux1ref aux1ref aux2ref aux2ref aux1ref],[Yref-40 Yref+40 Yref+40 Yref-40 Yref-40 aux3ref aux4ref aux4ref aux3ref aux3ref],'g','FaceAlpha',0.3,'Edgecolor','none','HitTest','off');    
                end
                Xall = [Xall; Xref];
                Yall = [Yall; Yref];
                allNames = [allNames; {sprintf('REF: %g_%g', Xref, Yref)}];
            end

            hold(app.UIAxes,'off')     
            app.UIAxes.ButtonDownFcn = @(src, event) showROITooltip(app, src, event, Xall, Yall, allNames);       
            updateLog(app, ' ', 'info');
            updateLog(app, 'Setup plotted.', 'plot');
            enableDefaultInteractivity(app.UIAxes);
        end

        % Value changed function: DatafolderpathEditField
        function DataFolderPath(app, event)
            value = app.DatafolderpathEditField.Value;
            % Check if data folder exists
            if ~isfolder(value)
                updateLog(app, 'Error: Data folder does not exist.', 'error');
                return;
            end
            
            % Check if required subfolders exist and have data
            satellite = app.InstrumentDropDown.Value;
            satellite = satellite(7:end);
            required_folders = {
                ['MODIS_Level_1B_', satellite]
                ['MODIS_Geolocation_', satellite]
                ['MODIS_Cloud_mask_', satellite]
            };
            
            for i = 1:length(required_folders)
                folder_path = fullfile(value, required_folders{i});
                if ~isfolder(folder_path)
                    updateLog(app, 'Error: Required folder not found.', 'error');
                    return;
                end
                
                % Check if folder has any .hdf files
                files = dir(fullfile(folder_path, '*.hdf'));
                if isempty(files)
                    updateLog(app, 'Warning: No .hdf files found at folder path.',  'warning');
                    return;
                end
            end
            
            updateLog(app, 'Data directory is valid and contains required files.', 'info');
        end

        % Value changed function: OutputfolderpathEditField
        function OutputFolderPath(app, event)
            value = app.OutputfolderpathEditField.Value;

            
            % Verify and create output directory if needed
            try
                if ~isfolder(value)
                    mkdir(value);
                    updateLog(app, 'Created output directory.',  'info');
                end
                
                % Test write permissions
                test_file = fullfile(value, 'test.txt');
                fid = fopen(test_file, 'w');
                if fid == -1
                    updateLog(app, 'Warning: Cannot write to output directory', 'warning');
                else
                    fclose(fid);
                    delete(test_file);
                    updateLog(app, 'Output directory is writable.',  'info');

                end
            catch err
                updateLog(app, sprintf('Error with output directory: %s', err.message), 'error');
            end
        end

        % Value changed function: EditField
        function EditFieldTransparencyValues(app, event)
            percentageValue = app.EditField.Value;
            
            % Convert percentage to a 0-1 value for the slider
            sliderValue = percentageValue / 100;
            
            % Update the slider's value
            app.TransparencySlider.Value = sliderValue;
            
        end

        % Button pushed function: HELPButton
        function HelpButtonPushed(app, event)
            % Path to the user guide
            web('https://docs.google.com/document/d/1hJs5zSnxPevz0XnofjE_R_tjXVHCOGf2BR2939O_4e8/edit?usp=sharing', '-browser');  % Opens in default web browser
        end

        % Button pushed function: PlotcompletionROIsButton
        function Plocompletionrois(app, event)
            updateLog(app, 'Plotting ROIs completion (may take time) ...', 'plot');
            switchToPlotMode(app, true);
            xlimits = get(app.UIAxes, 'XLim');
            ylimits = get(app.UIAxes, 'YLim');

            devlat_vector = str2num(app.CoordinatesSNkmEditField.Value);
            devlon_vector = str2num(app.CoordinatesWEkmEditField.Value);
            rois_selection = app.ROIsselectionDropDown.Value;
            satellite = app.InstrumentDropDown.Value;
            satellite = satellite(7:end); % Extract Aqua or Terra from MODIS/Aqua or MODIS/Terra
            
            % Clear and replot image
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            image(app.UIAxes, [-app.W_side app.E_side]/1000, [app.N_side -app.S_side]/1000, app.I);
            
            % Create grid or specific locations based on selection
            if strcmp(rois_selection, 'Grid')
                [X, Y] = meshgrid(devlon_vector, devlat_vector);
            else % 'Specific locations'
                X = devlon_vector;
                Y = devlat_vector;
            end
            
            % Calculate completion rates for each point
            completion_rates = zeros(size(X));
            % Count total number of raw data files
            data_path = app.DatafolderpathEditField.Value;
            path = fullfile(data_path, ['MODIS_Level_1B_' satellite]);
            files = dir(fullfile(path, '*.hdf'));
            total_files = length(files);
            
            % Also check geolocation and mask files to ensure complete sets
            geo_path = fullfile(data_path, ['MODIS_Geolocation_' satellite]);
            mask_path = fullfile(data_path, ['MODIS_Cloud_mask_' satellite]);
            
            geo_files = dir(fullfile(geo_path, '*.hdf'));
            mask_files = dir(fullfile(mask_path, '*.hdf'));
            
            % Use minimum number to ensure we only count complete sets
            total_files = min([length(files), length(geo_files), length(mask_files)]);
            
            % Process each location
            for i = 1:numel(X)
                filepath = fullfile(app.OutputfolderpathEditField.Value, ...
                                  sprintf('%g_%g', X(i), Y(i)), ...
                                  sprintf('results_band31_%s.txt', satellite));
                if exist(filepath, 'file')
                    try
                        data = load(filepath);
                        completion_rates(i) = 100 * size(data,1) / total_files;
                    catch
                        completion_rates(i) = 0;
                    end
                end
            end
            
            % Plot points with color based on completion rate
            scatter(app.UIAxes, X(:), Y(:), 100, completion_rates(:), 'filled', 'MarkerEdgeColor', 'k');
            colormap(app.UIAxes, 'parula');
            app.UIAxes.CLim = [0 100];
            cb = colorbar(app.UIAxes);
            ylabel(cb,'Completion Rate (%)');

            % Set axes properties
            axis(app.UIAxes, 'tight');
            xlabel(app.UIAxes, 'km');
            ylabel(app.UIAxes, 'km');
            title(app.UIAxes, sprintf('%s Data Completion Rates (%%)', satellite));
            
            set(app.UIAxes, 'XLim', xlimits);
            set(app.UIAxes, 'YLim', ylimits);   
            
            hold(app.UIAxes, 'off');   
            updateLog(app, ' ', 'info');
            updateLog(app, 'ROIs completion plotted.', 'plot');
        end

        % Button pushed function: PlotavailableROIsButton
        function PlotavailableROIsButtonPushed(app, event)
            updateLog(app, ' ', 'info');updateLog(app, ' ', 'info');updateLog(app, ' ', 'progress');
            updateLog(app, 'Plotting available ROIs ...', 'plot');
            switchToPlotMode(app, true);
            colorbar(app.UIAxes, 'off');
            xlimits=get(app.UIAxes, 'XLim');
            ylimits=get(app.UIAxes, 'YLim');  
            
            % Plot base image
            image(app.UIAxes,[-app.W_side app.E_side]/1000,[app.N_side -app.S_side]/1000,app.I);
            set(app.UIAxes,'YDir','normal')
            set(app.UIAxes, 'Visible', 'on')
            axis(app.UIAxes,'tight')
            xlabel(app.UIAxes,'km')
            ylabel(app.UIAxes,'km')
            set(app.UIAxes,'XLim',xlimits)
            set(app.UIAxes,'YLim',ylimits)   
            title(app.UIAxes, 'Available ROIs');
            hold(app.UIAxes,'on')
                          

            d = dir(app.OutputfolderpathEditField.Value);
            dfolders = d([d(:).isdir]);
            dfolders = dfolders(cellfun(@(x) ~isempty(regexp(x,  '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));
                
            % Initialize arrays
            X = [];
            Y = [];
            validFolderIdx = 1;
                
            % Process each folder
            for i = 1:length(dfolders)
                folderName = dfolders(i).name;
                underscorePos = strfind(folderName, '_');
                    
                % Only process if folder name contains underscore
                if ~isempty(underscorePos)
                    try
                        xVal = str2num(folderName(1:underscorePos(1)-1));
                        yVal = str2num(folderName(underscorePos(1)+1:end));
                        
                        % Only add if both values are valid numbers
                        if ~isempty(xVal) && ~isempty(yVal)
                            X(validFolderIdx) = xVal;
                            Y(validFolderIdx) = yVal;
                            validFolderIdx = validFolderIdx + 1;
                        end
                    catch
                        % Skip this folder if there's any error in conversion
                        continue
                    end
                end
            end
        
            % Only proceed if we found valid coordinates
            if ~isempty(X) && ~isempty(Y)
%                 plot(app.UIAxes,X,Y,'.r','MarkerSize',10)
                po = scatter(app.UIAxes, X(:), Y(:), 10, 'r', 'filled');
                roiNames = arrayfun(@(x,y) sprintf('%g_%g', x, y), X(:), Y(:), 'UniformOutput', false);
                Xcopy = X(:); Ycopy = Y(:);
                po.ButtonDownFcn = @(src, event) showROITooltip(app, src, event, Xcopy, Ycopy, roiNames);
                enableDefaultInteractivity(app.UIAxes);
            end
            hold(app.UIAxes,'off')     
            Xall = X(:); Yall = Y(:);
            roiNames = arrayfun(@(x,y) sprintf('%g_%g', x, y), Xall, Yall, 'UniformOutput', false);
            app.UIAxes.ButtonDownFcn = @(src, event) showROITooltip(app, src, event, Xall, Yall, roiNames);
            updateLog(app, ' ', 'info');
            updateLog(app, 'Available ROIs plotted.', 'plot');
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            a=dir(fullfile(app.OutputfolderpathEditField.Value,'AppImage*.png'));
            out=numel(a);
            exportgraphics(app.SSTARv10UIFigure,fullfile(app.OutputfolderpathEditField.Value,['AppImage' num2str(out+1) '.png']))
        end

        % Drop down opening function: ScenariotomapDropDown
        function ScenariotomapDropDownOpening(app, event)
            % Initialize the dropdown items for scenarios
            app.ScenariotomapDropDown.Items = {};  % Clear existing items in the scenario dropdown
            app.ScenariotoplotDropDown.Items{1} = '----';  % Set initial value for the plot dropdown
            if ~isfolder(app.OutputfolderpathEditField.Value)
                updateLog(app, 'Invalid folder path. Please select a valid directory.', 'error');
                return
            end
            % Get the list of subdirectories in the given folder path
            d = dir(app.OutputfolderpathEditField.Value);
            dfolders = d([d(:).isdir]);  
            dfolders = dfolders(cellfun(@(x) ~isempty(regexp(x,  '^-?\d*\.?\d+_-?\d*\.?\d+$', 'once')), {dfolders(:).name}));            
            % Initialize an empty cell array for storing the scenarios
            scenario = {};  % Store all scenario names here
            
            % Loop over each folder in dfolders
            for j = 1:length(dfolders)
                % Get the list of 'results.txt' files in the current folder
                scenarios = dir(fullfile(app.OutputfolderpathEditField.Value, dfolders(j).name, '*results.txt'));
                
                % Temporary storage for the scenarios in the current folder
                tempScenario = cell(1, length(scenarios));  % Row cell array to store scenario names
                
                % Loop through all scenarios in the current folder
                for i = 1:length(scenarios)
                    inputString = scenarios(i).name;  % Get the filename
                    underscorePositions = strfind(inputString, '_');  % Find positions of underscores in the filename
                    
                    % If there are at least two underscores, extract the part after the second underscore
                    if length(underscorePositions) >= 2
                        tempScenario{i} = inputString((underscorePositions(2) + 1):end);
                    else
                        tempScenario{i} = '';  % Handle cases with fewer than two underscores
                    end
                end
                
                % Concatenate valid (non-empty) entries from the current folder into the main scenario array
                scenario = horzcat(scenario, tempScenario(~cellfun('isempty', tempScenario)));
            end
            
            % Count occurrences of each unique string in the 'scenario' array
            [uniqueScenarios, ~, idx] = unique(scenario);  % Find unique scenarios
            counts = histcounts(idx, 1:(length(uniqueScenarios) + 1));  % Count how often each unique scenario appears
            
            % Filter scenarios that appear exactly 'length(dfolders)' times (i.e., repeated across all folders)
            repeatedScenarios = uniqueScenarios(counts == length(dfolders));
            
            % Ensure repeatedScenarios is a row cell array (in case it's a column vector)
            repeatedScenarios = repeatedScenarios(:)';  % Convert from column to row if necessary
            disp(repeatedScenarios);
            % Update the dropdown items for scenario selection, with an option to select a scenario
            app.ScenariotomapDropDown.Items = [{'Select a scenario'}, repeatedScenarios];
        end

        % Button pushed function: PlotmapforspecificdateButton
        function PlotmapforspecificdateButtonPushed3(app, event)
            app.PlotmapforspecificdateButton.BackgroundColor = [0.8,0.8,0.8];
            app.animation=false;
            updateLog(app, 'Creating map ...', 'plot');
            switchToPlotMode(app, true); 
            startSimulation3(app)
            app.hasPlotted = true;
            app.PlotmapforspecificdateButton.BackgroundColor = [0.90,0.90,0.90];
            %updateLog(app, ' ', 'info');
            %updateLog(app, 'Map created.', 'plot');
        end

        % Value changed function: FiltertypeDropDown
        function FiltertypeDropDownValueChanged(app, event)
            value = app.FiltertypeDropDown.Value;
        end

        % Button pushed function: CREATEANIMATIONButton
        function CREATEANIMATIONButtonPushed(app, event)
            app.CREATEANIMATIONButton.BackgroundColor = [0.8,0.8,0.8];
            updateLog(app, 'Creating animation in the output folder ...', 'plot');
            switchToPlotMode(app, true);
            app.animation = true;
            startSimulation3(app)
            app.CREATEANIMATIONButton.BackgroundColor = [0.39,0.83,0.07];
        end

        % Data changed function: HTML2
        function HTMLArea(app, event)
            data = app.HTML2.Data;
        end

        % Value changed function: ConfigurationfileEditField_2
        function ConfigPaths(app, event)
            value = app.ConfigurationfileEditField_2.Value;
            if ~isempty(value) && exist(value, 'file')
                try
                    % Read and apply config file parameters
                    config_data = readConfigFile(value);
                    applyConfigParameters(app, config_data);
                    updateLog(app, ' ', 'info');
                    updateLog(app, 'Parameters loaded from config file successfully.', 'info');
                catch ME
                    updateLog(app, ['Error loading config file: ' ME.message], 'error');
                end
            else
                updateLog(app, "Default configuration from 'DATA PROCESSING' will be used.", 'warning');
            end
                    
            function config_data = readConfigFile(filename)
                % Read existing config file into cell array
                fid = fopen(filename, 'r');
                config_data = {};
                tline = fgetl(fid);
                i = 1;
                while ischar(tline)
                    % Find position of first colon followed by a space
                    [start_idx, end_idx] = regexp(tline, ':\s');
                    if ~isempty(start_idx)
                        param_name = strtrim(tline(1:start_idx-1));
                        param_value = strtrim(tline(end_idx+1:end));
                        config_data{i,1} = param_name;
                        config_data{i,2} = param_value;
                        i = i + 1;
                    end
                    tline = fgetl(fid);
                end
                fclose(fid);
            end
            
            function applyConfigParameters(app, config_data)
                % Map config parameters to app fields
                for i = 1:size(config_data, 1)
                    param_name = config_data{i,1};
                    param_value = config_data{i,2};
                    %fprintf('Setting %s to: %s\n', param_name, param_value); % Debug line
                    switch param_name
                        case 'Data Folder'
                            app.DatafolderpathEditField.Value = param_value;
                        case 'Output Path'
                            app.OutputfolderpathEditField.Value = param_value;
                        case 'Latitude Reference'
                            app.LatitudeofthereferencelocationEditField.Value = str2double(param_value);
                        case 'Longitude Reference'
                            app.LongitudeofthereferencelocationEditField.Value = str2double(param_value);
                    end
                end
            end
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_5.SelectedObject;
            
        end

        % Value changed function: ConfigurationfileEditField_3
        function ConfigPaths2(app, event)
            value = app.ConfigurationfileEditField_3.Value;
            if ~isempty(value) && exist(value, 'file')
                try
                    % Read and apply config file parameters
                    config_data = readConfigFile(value);
                    applyConfigParameters(app, config_data);
                    updateLog(app, ' ', 'info');
                    updateLog(app, 'Parameters loaded from config file successfully.', 'info');
                catch ME
                    updateLog(app, ['Error loading config file: ' ME.message], 'error');
                end
            else
                updateLog(app, "Default configuration from 'DATA PROCESSING' will be used.", 'warning');
            end
                    
            function config_data = readConfigFile(filename)
                % Read existing config file into cell array
                fid = fopen(filename, 'r');
                config_data = {};
                tline = fgetl(fid);
                i = 1;
                while ischar(tline)
                    % Find position of first colon followed by a space
                    [start_idx, end_idx] = regexp(tline, ':\s');
                    if ~isempty(start_idx)
                        param_name = strtrim(tline(1:start_idx-1));
                        param_value = strtrim(tline(end_idx+1:end));
                        config_data{i,1} = param_name;
                        config_data{i,2} = param_value;
                        i = i + 1;
                        
                    end
                    tline = fgetl(fid);
                end
                fclose(fid);
            end
            
            function applyConfigParameters(app, config_data)
                % Map config parameters to app fields
                for i = 1:size(config_data, 1)
                    param_name = config_data{i,1};
                    param_value = config_data{i,2};
                    %fprintf('Setting %s to: %s\n', param_name, param_value); % Debug line
                    switch param_name
                        case 'Data Folder'
                            app.DatafolderpathEditField.Value = param_value;
                        case 'Output Path'
                            app.OutputfolderpathEditField.Value = param_value;
                        case 'Latitude Reference'
                            app.LatitudeofthereferencelocationEditField.Value = str2double(param_value);
                        case 'Longitude Reference'
                            app.LongitudeofthereferencelocationEditField.Value = str2double(param_value);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SSTARv10UIFigure = uifigure('Visible', 'off');
            app.SSTARv10UIFigure.Name = 'SSTAR v1.0';
            app.SSTARv10UIFigure.Icon = 'SSTAR_logo.png';
            app.SSTARv10UIFigure.Tag = 'ToLabel';
            app.UIAxes = uiaxes(app.SSTARv10UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'ToLabel';
            app.TabGroup = uitabgroup(app.SSTARv10UIFigure);
            app.TabGroup.Tooltip = {'Higher values = smoother, more detailed maps, but slower computation.'};
            app.TabGroup.Tag = 'ToLabel';
            app.DATAPROCESSINGTab = uitab(app.TabGroup);
            app.DATAPROCESSINGTab.Title = '   DATA PROCESSING             ';
            app.Panel_3 = uipanel(app.DATAPROCESSINGTab);
            app.Panel_3.Tag = 'ToLabel';
            app.CoordinatesWEkmEditFieldLabel = uilabel(app.Panel_3);
            app.CoordinatesWEkmEditFieldLabel.Tag = 'ToLabel';
            app.CoordinatesWEkmEditFieldLabel.Text = 'Coordinates WE (km):';
            app.CoordinatesWEkmEditField = uieditfield(app.Panel_3, 'text');
            app.CoordinatesWEkmEditField.ValueChangedFcn = createCallbackFcn(app, @CoordinatesWEkmEditFieldValueChanged, true);
            app.CoordinatesWEkmEditField.Tooltip = {'Format: min:step:max (in km)'};
            app.CoordinatesWEkmEditField.Value = '-18:2:10';
            app.CoordinatesSNkmEditFieldLabel = uilabel(app.Panel_3);
            app.CoordinatesSNkmEditFieldLabel.Text = 'Coordinates SN (km):';
            app.CoordinatesSNkmEditField = uieditfield(app.Panel_3, 'text');
            app.CoordinatesSNkmEditField.ValueChangedFcn = createCallbackFcn(app, @CoordinatesSNkmEditFieldValueChanged, true);
            app.CoordinatesSNkmEditField.Tooltip = {'Format: min:step:max (in km)'};
            app.CoordinatesSNkmEditField.Value = '-16:2:32';
            app.LongitudeofthereferencelocationEditFieldLabel = uilabel(app.Panel_3);
            app.LongitudeofthereferencelocationEditFieldLabel.Text = 'Longitude of the reference location (°):';
            app.LongitudeofthereferencelocationEditField = uieditfield(app.Panel_3, 'numeric');
            app.LongitudeofthereferencelocationEditField.ValueDisplayFormat = '%11.5g';
            app.LongitudeofthereferencelocationEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeofthereferencelocationEditFieldValueChanged, true);
            app.LongitudeofthereferencelocationEditField.Tooltip = {'Volcano longitude (https://volcano.si.edu/)'};
            app.LongitudeofthereferencelocationEditField.Value = -163.97;
            app.LatitudeofthereferencelocationEditFieldLabel = uilabel(app.Panel_3);
            app.LatitudeofthereferencelocationEditFieldLabel.Text = 'Latitude of the reference location (°):';
            app.LatitudeofthereferencelocationEditField = uieditfield(app.Panel_3, 'numeric');
            app.LatitudeofthereferencelocationEditField.ValueDisplayFormat = '%11.5g';
            app.LatitudeofthereferencelocationEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeofthereferencelocationEditFieldValueChanged, true);
            app.LatitudeofthereferencelocationEditField.Tooltip = {'Volcano latitude (https://volcano.si.edu/)'};
            app.LatitudeofthereferencelocationEditField.Value = 54.756;
            app.DeleteexistingoutputfilesDropDownLabel = uilabel(app.Panel_3);
            app.DeleteexistingoutputfilesDropDownLabel.Text = 'Delete existing output files:';
            app.DeleteexistingoutputfilesDropDown = uidropdown(app.Panel_3);
            app.DeleteexistingoutputfilesDropDown.Items = {'Yes', 'No'};
            app.DeleteexistingoutputfilesDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteexistingoutputfilesDropDownValueChanged, true);
            app.DeleteexistingoutputfilesDropDown.Tooltip = {'Yes: Starts fresh analysis'; 'No: Continues from last analyzed file'};
            app.DeleteexistingoutputfilesDropDown.Value = 'No';
            app.InstrumentDropDownLabel = uilabel(app.Panel_3);
            app.InstrumentDropDownLabel.Text = 'Instrument: ';
            app.InstrumentDropDown = uidropdown(app.Panel_3);
            app.InstrumentDropDown.Items = {'MODIS/Terra', 'MODIS/Aqua'};
            app.InstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDownValueChanged, true);
            app.InstrumentDropDown.Value = 'MODIS/Aqua';
            app.RUNButton = uibutton(app.Panel_3, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.STOPButton = uibutton(app.Panel_3, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.PlotareaofanalysisButton = uibutton(app.Panel_3, 'push');
            app.PlotareaofanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PlotareaofanalysisButtonPushed, true);
            app.PlotareaofanalysisButton.Text = 'Plot area of analysis';
            app.PlotcenterROIsButton = uibutton(app.Panel_3, 'push');
            app.PlotcenterROIsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcenterROIsButtonPushed, true);
            app.PlotcenterROIsButton.Tooltip = {'in the area defined in coordinates'};
            app.PlotcenterROIsButton.Text = 'Plot center ROIs';
            app.ROIsselectionDropDownLabel = uilabel(app.Panel_3);
            app.ROIsselectionDropDownLabel.Text = 'ROIs selection:';
            app.ROIsselectionDropDown = uidropdown(app.Panel_3);
            app.ROIsselectionDropDown.Items = {'Grid', 'Specific locations'};
            app.ROIsselectionDropDown.Tooltip = {'Grid: Analyze regular grid points'; 'Specific locations: Analyze custom points'};
            app.ROIsselectionDropDown.Value = 'Grid';
            app.DatafolderpathEditFieldLabel = uilabel(app.Panel_3);
            app.DatafolderpathEditFieldLabel.Text = 'Data folder path';
            app.DatafolderpathEditField = uieditfield(app.Panel_3, 'text');
            app.DatafolderpathEditField.ValueChangedFcn = createCallbackFcn(app, @DataFolderPath, true);
            app.DatafolderpathEditField.Tooltip = {'Path to the data folders including MODIS_Level_1B_Aqua, MODIS_Geolocation_Aqua, MODIS_Cloud_Mask_Aqua and same for Terra folders'};
            app.DatafolderpathEditField.Value = '\\fbk-g53l1g6\Shared Data\Shishaldin';
            app.OutputfolderpathEditFieldLabel = uilabel(app.Panel_3);
            app.OutputfolderpathEditFieldLabel.Text = 'Output folder path';
            app.OutputfolderpathEditField = uieditfield(app.Panel_3, 'text');
            app.OutputfolderpathEditField.ValueChangedFcn = createCallbackFcn(app, @OutputFolderPath, true);
            app.OutputfolderpathEditField.Tooltip = {'Path for the results folders and files'};
            app.OutputfolderpathEditField.Value = 'D:\Shared Data\SSTAR_project';
            app.PlotcompletionROIsButton = uibutton(app.Panel_3, 'push');
            app.PlotcompletionROIsButton.ButtonPushedFcn = createCallbackFcn(app, @Plocompletionrois, true);
            app.PlotcompletionROIsButton.Tooltip = {'in the area defined in coordinates (might take time)'};
            app.PlotcompletionROIsButton.Text = 'Plot completion ROIs';
            app.TIMESERIESTab = uitab(app.TabGroup);
            app.TIMESERIESTab.Title = '   TIME SERIES             ';
            app.TIMESERIESTab.Tag = 'ToLabel';
            app.Panel = uipanel(app.TIMESERIESTab);
            app.LightconditionsDropDownLabel = uilabel(app.Panel);
            app.LightconditionsDropDownLabel.Tag = 'ToLabel';
            app.LightconditionsDropDownLabel.Text = 'Light conditions:';
            app.LightconditionsDropDown = uidropdown(app.Panel);
            app.LightconditionsDropDown.Items = {'Day&Night', 'Daylight', 'Nightlight'};
            app.LightconditionsDropDown.ValueChangedFcn = createCallbackFcn(app, @LightconditionsDropDownValueChanged, true);
            app.LightconditionsDropDown.Tag = 'ToLabel';
            app.LightconditionsDropDown.Value = 'Day&Night';
            app.OrderofthelowpassfilteryearsEditField = uieditfield(app.Panel, 'text');
            app.OrderofthelowpassfilteryearsEditField.ValueChangedFcn = createCallbackFcn(app, @OrderofthelowpassfilteryearsEditFieldValueChanged, true);
            app.OrderofthelowpassfilteryearsEditField.Tooltip = {'Higher values smooth more but increase processing time'};
            app.OrderofthelowpassfilteryearsEditField.Value = '365';
            app.AcceptancethresholdEditFieldLabel = uilabel(app.Panel);
            app.AcceptancethresholdEditFieldLabel.Text = 'Acceptance threshold (%):';
            app.AcceptancethresholdEditField = uieditfield(app.Panel, 'text');
            app.AcceptancethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AcceptancethresholdEditFieldValueChanged, true);
            app.AcceptancethresholdEditField.Tooltip = {'Minimum % of cloud-free pixels required'};
            app.AcceptancethresholdEditField.Value = '0';
            app.IterationsforuncertaintyquantificationEditFieldLabel = uilabel(app.Panel);
            app.IterationsforuncertaintyquantificationEditFieldLabel.Text = 'Iterations (for uncertainty quantification):';
            app.IterationsforuncertaintyquantificationEditField = uieditfield(app.Panel, 'text');
            app.IterationsforuncertaintyquantificationEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsforuncertaintyquantificationEditFieldValueChanged, true);
            app.IterationsforuncertaintyquantificationEditField.Tooltip = {'Minimum 3, error shown from 100'};
            app.IterationsforuncertaintyquantificationEditField.Value = '3';
            app.SideROIkmDropDownLabel = uilabel(app.Panel);
            app.SideROIkmDropDownLabel.Text = 'Side ROI (km):';
            app.SideROIkmDropDown = uidropdown(app.Panel);
            app.SideROIkmDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '50', '60', '70', '80'};
            app.SideROIkmDropDown.Editable = 'on';
            app.SideROIkmDropDown.ValueChangedFcn = createCallbackFcn(app, @SideROIkmDropDownValueChanged, true);
            app.SideROIkmDropDown.Value = '2';
            app.SidereferencekmDropDownLabel = uilabel(app.Panel);
            app.SidereferencekmDropDownLabel.Text = 'Side reference (km):';
            app.SidereferencekmDropDown = uidropdown(app.Panel);
            app.SidereferencekmDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '50', '60', '70'};
            app.SidereferencekmDropDown.Editable = 'on';
            app.SidereferencekmDropDown.ValueChangedFcn = createCallbackFcn(app, @SidereferencekmDropDownValueChanged, true);
            app.SidereferencekmDropDown.Value = '60';
            app.ButtonGroup_2 = uibuttongroup(app.Panel);
            app.InteriorButton = uiradiobutton(app.ButtonGroup_2);
            app.InteriorButton.Tooltip = {'Use central area'};
            app.InteriorButton.Text = 'Interior';
            app.ExteriorButton = uiradiobutton(app.ButtonGroup_2);
            app.ExteriorButton.Tooltip = {'Use surrounding area'};
            app.ExteriorButton.Text = 'Exterior';
            app.ExteriorButton.Value = true;
            app.ROIstoanalyzeDropDownLabel = uilabel(app.Panel);
            app.ROIstoanalyzeDropDownLabel.Text = 'ROIs to analyze:';
            app.ROIstoanalyzeDropDown = uidropdown(app.Panel);
            app.ROIstoanalyzeDropDown.Items = {'All'};
            app.ROIstoanalyzeDropDown.Editable = 'on';
            app.ROIstoanalyzeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ROIstoanalyzeDropDownOpening, true);
            app.ROIstoanalyzeDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIstoanalyzeDropDownValueChanged, true);
            app.ROIstoanalyzeDropDown.Value = 'All';
            app.ReferenceROIDropDownLabel = uilabel(app.Panel);
            app.ReferenceROIDropDownLabel.Text = 'Reference ROI:';
            app.ReferenceROIDropDown = uidropdown(app.Panel);
            app.ReferenceROIDropDown.Items = {'0_0'};
            app.ReferenceROIDropDown.Editable = 'on';
            app.ReferenceROIDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ReferenceROIDropDownOpening, true);
            app.ReferenceROIDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceROIDropDownValueChanged, true);
            app.ReferenceROIDropDown.Value = '0_0';
            app.ROItoplotLabel = uilabel(app.Panel);
            app.ROItoplotLabel.Text = 'ROI to plot:';
            app.ROItoplotDropDown = uidropdown(app.Panel);
            app.ROItoplotDropDown.Items = {'0_0'};
            app.ROItoplotDropDown.Editable = 'on';
            app.ROItoplotDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ROItoplotDropDownOpening, true);
            app.ROItoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @ROItoplotDropDownValueChanged, true);
            app.ROItoplotDropDown.Value = '0_0';
            app.ScenariotoplotDropDownLabel = uilabel(app.Panel);
            app.ScenariotoplotDropDownLabel.Text = 'Scenario to plot:';
            app.ScenariotoplotDropDown = uidropdown(app.Panel);
            app.ScenariotoplotDropDown.Items = {'----'};
            app.ScenariotoplotDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ScenariotoplotDropDownOpening, true);
            app.ScenariotoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenariotoplotDropDownValueChanged, true);
            app.ScenariotoplotDropDown.Value = '----';
            app.PlottimeseriesButton = uibutton(app.Panel, 'push');
            app.PlottimeseriesButton.ButtonPushedFcn = createCallbackFcn(app, @PlottimeseriesButtonPushed, true);
            app.PlottimeseriesButton.Text = 'Plot time series';
            app.FiltertypeDropDownLabel = uilabel(app.Panel);
            app.FiltertypeDropDownLabel.Text = 'Filter type:';
            app.FiltertypeDropDown = uidropdown(app.Panel);
            app.FiltertypeDropDown.Items = {'Low-pass (wavelet + median)', 'Low-pass (median)', 'High-pass (Butterworth)', 'Band-pass (Butterworth)'};
            app.FiltertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltertypeDropDownValueChanged, true);
            app.FiltertypeDropDown.Tooltip = {'Low-pass: Long-term trends (years)'; 'High-pass: Short-term changes (days)'};
            app.FiltertypeDropDown.Value = 'Low-pass (median)';
            app.PlotsetupButton = uibutton(app.Panel, 'push');
            app.PlotsetupButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsetupButtonPushed, true);
            app.PlotsetupButton.Text = 'Plot setup';
            app.DeletepreviousresultsCheckBox = uicheckbox(app.Panel);
            app.DeletepreviousresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeletepreviousresultsCheckBoxValueChanged, true);
            app.DeletepreviousresultsCheckBox.Text = 'Delete previous results';
            app.RUNButton_2 = uibutton(app.Panel, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.TemporalcorrectiondaysEditField_2Label = uilabel(app.Panel);
            app.TemporalcorrectiondaysEditField_2Label.Text = 'Temporal correction (days):';
            app.TemporalcorrectiondaysEditField_2 = uieditfield(app.Panel, 'text');
            app.TemporalcorrectiondaysEditField_2.Tooltip = {'Corrects time lag from filtering process. Set to 0 if no reference events available, or adjust based on known thermal events. Can check for event with the next tab.'};
            app.TemporalcorrectiondaysEditField_2.Value = '0';
            app.InterpolationmethodDropDownLabel_2 = uilabel(app.Panel);
            app.InterpolationmethodDropDownLabel_2.Text = 'Cutoff period (days):';
            app.PlotavailableROIsButton = uibutton(app.Panel, 'push');
            app.PlotavailableROIsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotavailableROIsButtonPushed, true);
            app.PlotavailableROIsButton.Text = 'Plot available ROIs';
            app.ButtonGroup_3 = uibuttongroup(app.Panel);
            app.AnomalyButton = uiradiobutton(app.ButtonGroup_3);
            app.AnomalyButton.Tooltip = {'Thermal anomaly = ROI - REF'};
            app.AnomalyButton.Text = 'Anomaly';
            app.AnomalyButton.Value = true;
            app.ROIButton = uiradiobutton(app.ButtonGroup_3);
            app.ROIButton.Tooltip = {'ROI temperature relative to the initial value'};
            app.ROIButton.Text = 'ROI';
            app.REFButton = uiradiobutton(app.ButtonGroup_3);
            app.REFButton.Tooltip = {'Reference area temperature relative to its initial value'};
            app.REFButton.Text = 'REF';
            app.InterpolationmethodDropDownLabel = uilabel(app.Panel);
            app.InterpolationmethodDropDownLabel.Text = 'Interpolation method:';
            app.InterpolationmethodDropDown = uidropdown(app.Panel);
            app.InterpolationmethodDropDown.Items = {'Nearest', 'Spline', 'Linear', 'Pchip', 'Makima'};
            app.InterpolationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationmethodDropDownValueChanged, true);
            app.InterpolationmethodDropDown.Value = 'Nearest';
            app.PlotareaofanalysisButton_2 = uibutton(app.Panel, 'push');
            app.PlotareaofanalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotareaofanalysisButtonPushed, true);
            app.PlotareaofanalysisButton_2.Text = 'Plot area of analysis';
            app.ReferencedatesCheckBox = uicheckbox(app.Panel);
            app.ReferencedatesCheckBox.Tooltip = {'Requires an external file "reference_dates.csv"'};
            app.ReferencedatesCheckBox.Text = 'Reference dates';
            app.ConfigurationfileLabel = uilabel(app.Panel);
            app.ConfigurationfileLabel.Text = 'Configuration file:';
            app.ConfigurationfileEditField_2 = uieditfield(app.Panel, 'text');
            app.ConfigurationfileEditField_2.ValueChangedFcn = createCallbackFcn(app, @ConfigPaths, true);
            app.ConfigurationfileEditField_2.Tooltip = {'Enter the path of simulation_config.txt in the output folder. If empty the parameters from data processing will be used.'};
            app.ConfigurationfileEditField_2.Value = 'D:\Shared Data\SSTAR_project\Output\config.txt';
            app.ExportdatatocsvCheckBox = uicheckbox(app.Panel);
            app.ExportdatatocsvCheckBox.Text = 'Export data to csv';
            app.SPATIOTEMPORALMAPTab = uitab(app.TabGroup);
            app.SPATIOTEMPORALMAPTab.Tooltip = {''};
            app.SPATIOTEMPORALMAPTab.Title = '   SPATIOTEMPORAL MAP             ';
            app.Panel_2 = uipanel(app.SPATIOTEMPORALMAPTab);
            app.WildfiremaskCheckBox = uicheckbox(app.Panel_2);
            app.WildfiremaskCheckBox.Tooltip = {'Require an external file "wildfires.csv"'};
            app.WildfiremaskCheckBox.Text = 'Wildfire mask';
            app.AnomalylimitsKEditFieldLabel = uilabel(app.Panel_2);
            app.AnomalylimitsKEditFieldLabel.Text = 'Anomaly limits (K):';
            app.AnomalylimitsKEditField = uieditfield(app.Panel_2, 'text');
            app.AnomalylimitsKEditField.Tooltip = {'Sets color scale range for thermal anomalies visualization. Adjust to highlight subtle or extreme variations.'};
            app.AnomalylimitsKEditField.Value = '0';
            app.TemporalcorrectiondaysEditFieldLabel = uilabel(app.Panel_2);
            app.TemporalcorrectiondaysEditFieldLabel.Text = 'Temporal correction (days):';
            app.TemporalcorrectiondaysEditField = uieditfield(app.Panel_2, 'text');
            app.TemporalcorrectiondaysEditField.Tooltip = {'Corrects time lag from filtering process. Set to 0 if no reference events available, or adjust based on known thermal events.'};
            app.TemporalcorrectiondaysEditField.Value = '0';
            app.TransparencyLabel = uilabel(app.Panel_2);
            app.TransparencyLabel.Text = 'Transparency (%):';
            app.TransparencySlider = uislider(app.Panel_2);
            app.TransparencySlider.Limits = [0 0.1];
            app.TransparencySlider.MajorTicks = [];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.TransparencySlider.Tooltip = {'Transparency of the plot to potentially visualized the background satelite map.'};
            app.TransparencySlider.Value = 0.1;
            app.AnomalylimitsKEditField_2 = uieditfield(app.Panel_2, 'text');
            app.AnomalylimitsKEditField_2.Value = '1';
            app.CREATEANIMATIONButton = uibutton(app.Panel_2, 'push');
            app.CREATEANIMATIONButton.ButtonPushedFcn = createCallbackFcn(app, @CREATEANIMATIONButtonPushed, true);
            app.CREATEANIMATIONButton.Tooltip = {'Create a video of all the maps during the studied period'};
            app.CREATEANIMATIONButton.Text = 'CREATE ANIMATION';
            app.AnomalylimitsKEditFieldLabel_2 = uilabel(app.Panel_2);
            app.AnomalylimitsKEditFieldLabel_2.Text = 'max';
            app.AnomalylimitsKEditFieldLabel_3 = uilabel(app.Panel_2);
            app.AnomalylimitsKEditFieldLabel_3.Text = 'min';
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField.Limits = [0 100];
            app.EditField.ValueDisplayFormat = '%.3f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldTransparencyValues, true);
            app.EditField.Value = 10;
            app.PlotmapforspecificdateButton = uibutton(app.Panel_2, 'push');
            app.PlotmapforspecificdateButton.ButtonPushedFcn = createCallbackFcn(app, @PlotmapforspecificdateButtonPushed3, true);
            app.PlotmapforspecificdateButton.Tooltip = {'Plot one map at the selected date'};
            app.PlotmapforspecificdateButton.Text = 'Plot map for specific date';
            app.ButtonGroup_4 = uibuttongroup(app.Panel_2);
            app.AnomalyButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.AnomalyButton_2.Tooltip = {'Thermal anomaly = ROI - REF'};
            app.AnomalyButton_2.Text = 'Anomaly';
            app.AnomalyButton_2.Value = true;
            app.ROIButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.ROIButton_2.Tooltip = {'ROI temperature relative to the initial value'};
            app.ROIButton_2.Text = 'ROI';
            app.PlotareaofanalysisButton_3 = uibutton(app.Panel_2, 'push');
            app.PlotareaofanalysisButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotareaofanalysisButtonPushed, true);
            app.PlotareaofanalysisButton_3.Text = 'Plot area of analysis';
            app.ScenariotomapDropDownLabel = uilabel(app.Panel_2);
            app.ScenariotomapDropDownLabel.Text = 'Scenario to map:';
            app.ScenariotomapDropDown = uidropdown(app.Panel_2);
            app.ScenariotomapDropDown.Items = {'----'};
            app.ScenariotomapDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ScenariotomapDropDownOpening, true);
            app.ScenariotomapDropDown.Value = '----';
            app.ButtonGroup_5 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.AbsoluteButton = uiradiobutton(app.ButtonGroup_5);
            app.AbsoluteButton.Tooltip = {'Raw anomaly'};
            app.AbsoluteButton.Text = 'Absolute';
            app.AbsoluteButton.Value = true;
            app.NormalizedcumulativeButton = uiradiobutton(app.ButtonGroup_5);
            app.NormalizedcumulativeButton.Tooltip = {'Temporal normalization  using only previous dates and spatial normalization.'};
            app.NormalizedcumulativeButton.Text = 'Normalized cumulative';
            app.SpatiallynormalizedButton = uiradiobutton(app.ButtonGroup_5);
            app.SpatiallynormalizedButton.Tooltip = {'Spatial normalization for each date'};
            app.SpatiallynormalizedButton.Text = 'Spatially normalized';
            app.DateLabel = uilabel(app.Panel_2);
            app.DateLabel.Text = 'Specific date:';
            app.SpecificdateDatePicker = uidatepicker(app.Panel_2);
            app.SpecificdateDatePicker.Value = datetime([2017 8 22]);
            app.TypeofrepresentationLabel = uilabel(app.Panel_2);
            app.TypeofrepresentationLabel.Text = 'Type of representation:';
            app.ConfigurationfileEditField_3Label = uilabel(app.Panel_2);
            app.ConfigurationfileEditField_3Label.Text = 'Configuration file:';
            app.ConfigurationfileEditField_3 = uieditfield(app.Panel_2, 'text');
            app.ConfigurationfileEditField_3.ValueChangedFcn = createCallbackFcn(app, @ConfigPaths2, true);
            app.ConfigurationfileEditField_3.Tooltip = {'Enter the path of simulation_config.txt in the output folder. If empty the parameters from data processing will be used.'};
            app.ConfigurationfileEditField_3.Value = 'D:\Shared Data\SSTAR_project\Output\config.txt';
            app.SaveindividualframesCheckBox = uicheckbox(app.Panel_2);
            app.SaveindividualframesCheckBox.Tooltip = {'Save individual frame as image in AppAnimation*_frames folder'};
            app.SaveindividualframesCheckBox.Text = 'Save individual frames';
            app.SaveindividualframesCheckBox.Value = true;
            app.ResolutionLabel = uilabel(app.Panel_2);
            app.ResolutionLabel.Text = 'Resolution (grid points):';
            app.ResolutiongridpointsEditField = uieditfield(app.Panel_2, 'numeric');
            app.ResolutiongridpointsEditField.Limits = [0 Inf];
            app.ResolutiongridpointsEditField.Tooltip = {'Higher values = smoother, more detailed maps, but slower computation.'};
            app.ResolutiongridpointsEditField.Value = 400;
            app.StartLabel = uilabel(app.Panel_2);
            app.StartLabel.Tooltip = {''};
            app.StartLabel.Text = 'Start:';
            app.StartDatePicker = uidatepicker(app.Panel_2);
            app.StartDatePicker.Tooltip = {''};
            app.EndLabel = uilabel(app.Panel_2);
            app.EndLabel.Tooltip = {''};
            app.EndLabel.Text = 'End: ';
            app.EndDatePicker = uidatepicker(app.Panel_2);
            app.EndDatePicker.Tooltip = {''};
            app.ReferencestartLabel = uilabel(app.Panel_2);
            app.ReferencestartLabel.Text = 'Reference start:';
            app.ReferencestartDatePicker = uidatepicker(app.Panel_2);
            app.ReferenceendLabel = uilabel(app.Panel_2);
            app.ReferenceendLabel.Text = 'Reference end:';
            app.ReferenceendDatePicker = uidatepicker(app.Panel_2);
            app.ExportCSVCheckBox = uicheckbox(app.Panel_2);
            app.ExportCSVCheckBox.Text = 'Export CSV';
            app.HELPButton = uibutton(app.SSTARv10UIFigure, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HELPButton.Tooltip = {''};
            app.HELPButton.Text = 'HELP';
            app.SAVEButton = uibutton(app.SSTARv10UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Tooltip = {''};
            app.SAVEButton.Text = 'SAVE';
            app.HTML2 = uihtml(app.SSTARv10UIFigure);
            app.HTML2.HTMLSource = ' ';
            app.HTML2.DataChangedFcn = createCallbackFcn(app, @HTMLArea, true);
            app.HTML2.Tooltip = {''};
            app.Image = uiimage(app.SSTARv10UIFigure);
            app.Image.Tooltip = {''};
            % Show the figure after all components are created
            app.SSTARv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSTAR_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SSTARv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SSTARv10UIFigure)
        end
    end
end